classdef MUedit_peeloff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_4                        matlab.ui.container.Panel
        ClearflaggedcacheCheckBox      matlab.ui.control.CheckBox
        SILCheckBox                    matlab.ui.control.CheckBox
        CheckBox                       matlab.ui.control.CheckBox
        ReferenceDropDown_2            matlab.ui.control.DropDown
        ReferenceDropDown_2Label       matlab.ui.control.Label
        RemoveduplicatesbetweengridsButton  matlab.ui.control.Button
        RemoveduplicateswithingridsButton  matlab.ui.control.Button
        PlotMUfiringratesButton        matlab.ui.control.Button
        SavetheeditionLabel            matlab.ui.control.Label
        VisualisationLabel             matlab.ui.control.Label
        BatchprocessingLabel           matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        PlotMUspiketrainsButton        matlab.ui.control.Button
        RemoveflaggedMUButton          matlab.ui.control.Button
        UpdateallMUfiltersButton       matlab.ui.control.Button
        RemovealltheoutliersButton     matlab.ui.control.Button
        ImportdataButton               matlab.ui.control.Button
        SelectfileButton_2             matlab.ui.control.Button
        EditField_saving_2             matlab.ui.control.EditField
        Panel_3                        matlab.ui.container.Panel
        IgnorewindowsCheckBox          matlab.ui.control.CheckBox
        AddwindowButton                matlab.ui.control.Button
        VisualizewindowDropDownLabel   matlab.ui.control.Label
        VisualizewindowDropDown        matlab.ui.control.DropDown
        IgnorewindowsduringupdateSwitch  matlab.ui.control.Switch
        IgnoreselectedwindowsduringfilterupdateLabel  matlab.ui.control.Label
        seperatenumbersbycommaEditField_2  matlab.ui.control.EditField
        seperatenumbersbycommaEditField_2Label  matlab.ui.control.Label
        closefiguresButton             matlab.ui.control.Button
        plotSTAButton                  matlab.ui.control.Button
        UsepeeledoffsignalCheckBox     matlab.ui.control.CheckBox
        PlotpeeloffsourcesButton       matlab.ui.control.Button
        PeeloffMUofcurrentgridSwitchLabel  matlab.ui.control.Label
        seperatenumbersbycommaEditField  matlab.ui.control.EditField
        seperatenumbersbycommaLabel    matlab.ui.control.Label
        PeeloffMUofcurrentgridSwitch   matlab.ui.control.Switch
        FlagMUforDropDownLabel         matlab.ui.control.Label
        FlagMUforDropDown              matlab.ui.control.DropDown
        LockspikesButton               matlab.ui.control.Button
        UndoButton                     matlab.ui.control.Button
        EditField_2                    matlab.ui.control.EditField
        ExtendMUfilter                 matlab.ui.control.Button
        UpdateMUfilterButton           matlab.ui.control.Button
        ScrollrightButton              matlab.ui.control.Button
        ZoomoutButton                  matlab.ui.control.Button
        ZoominButton                   matlab.ui.control.Button
        ScrollleftButton               matlab.ui.control.Button
        DeleteDRButton                 matlab.ui.control.Button
        DeletespikesButton             matlab.ui.control.Button
        AddspikesButton                matlab.ui.control.Button
        RemoveoutliersButton           matlab.ui.control.Button
        MUdisplayedDropDownLabel       matlab.ui.control.Label
        MUdisplayedDropDown            matlab.ui.control.DropDown
        UIAxesSIL                      matlab.ui.control.UIAxes
        UIAxesDR                       matlab.ui.control.UIAxes
        UIAxesSpiketrain               matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        SegmentsessionButton           matlab.ui.control.Button
        SetconfigurationButton         matlab.ui.control.Button
        ContrastfunctionDropDown       matlab.ui.control.DropDown
        ContrastfunctionLabel          matlab.ui.control.Label
        DuplicatethresholdEditField    matlab.ui.control.NumericEditField
        DuplicatethresholdEditFieldLabel  matlab.ui.control.Label
        COVthresholdEditField          matlab.ui.control.NumericEditField
        COVthresholdEditFieldLabel     matlab.ui.control.Label
        ThresholdtargetEditField       matlab.ui.control.NumericEditField
        ThresholdtargetEditFieldLabel  matlab.ui.control.Label
        RefineMUsDropDown              matlab.ui.control.DropDown
        RefineMUsDropDownLabel         matlab.ui.control.Label
        InitialisationDropDown         matlab.ui.control.DropDown
        InitialisationDropDownLabel    matlab.ui.control.Label
        CoVfilterDropDown              matlab.ui.control.DropDown
        CoVfilterDropDownLabel         matlab.ui.control.Label
        NumberofwindowsEditField       matlab.ui.control.NumericEditField
        NumberofwindowsEditFieldLabel  matlab.ui.control.Label
        PeeloffDropDown                matlab.ui.control.DropDown
        PeeloffDropDownLabel           matlab.ui.control.Label
        CheckEMGDropDown               matlab.ui.control.DropDown
        CheckEMGDropDownLabel          matlab.ui.control.Label
        ReferenceDropDown              matlab.ui.control.DropDown
        ReferenceDropDownLabel         matlab.ui.control.Label
        NbofextendedchannelsEditField  matlab.ui.control.NumericEditField
        NbofextendedchannelsLabel      matlab.ui.control.Label
        SelectfileButton               matlab.ui.control.Button
        EditField_saving_3             matlab.ui.control.EditField
        NumberofiterationsEditField_2  matlab.ui.control.NumericEditField
        NumberofiterationsEditField_2Label  matlab.ui.control.Label
        SILthresholdEditField          matlab.ui.control.NumericEditField
        SILthresholdEditFieldLabel     matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        EditField                      matlab.ui.control.EditField
        UIAxes_Decomp_2                matlab.ui.control.UIAxes
        UIAxes_Decomp_1                matlab.ui.control.UIAxes
        tabs                           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        filename            % File to decompose
        pathname            % Folder where decomposed data will be saved

        filename2           % File to edit
        pathname2            % Folder where edited data will be saved

        MUdecomp            % Data for MU decomposition
        Configuration       % Object for recording configuration

        MUedition           % Data for MU edition accross contractions and intensities
        Backup              % Data for MU edition accross contractions and intensities
        Flagged             % MU that are flagged get stored in here.
        
        graphstart          % first time point of the graph for edition
        graphend            % last time point of the graph for edition
        roi                 % Region of interest for edition
        figHandles          % figure handles
                
    end
    
    properties (Access = public)
    end
    
    methods (Access = public)
        
        function sta_MUedit(app)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            grd = str2num(C{2});
            mu = str2num(C{4});
            
            nr_cha          = cell2mat(cellfun(@(grd) size(grd,1), app.MUedition.signal.coordinates, 'UniformOutput', false));%nr of channels per grid
            emg_grd         = app.MUedition.signal.data(sum(nr_cha(1:grd-1))+1:sum(nr_cha(1:grd-1))+nr_cha(grd),:); %get signals of this grid
            grid_order      = app.MUedition.signal.coordinates{grd};
            
            bad_sig         = app.MUedition.signal.EMGmask{grd};
            window_size     = 160;
            
            
            idx             = find(app.MUedition.edition.time > app.graphstart & app.MUedition.edition.time < app.graphend);
            firing          = intersect(idx(round(0.1*app.MUedition.signal.fsamp):end-round(0.1*app.MUedition.signal.fsamp)),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});
            
            emg_grd     = emg_grd(bad_sig==0,:);
            grid_order  = grid_order(bad_sig==0,:);
            
            electrodes = 1:size(emg_grd,1);
            
            spans   = arrayfun(@(fir) fir-round(window_size/2)+1:1:fir+round(window_size/2),firing,'UniformOutput',false);
            stas    = arrayfun(@(elc) stas_per_electrode(elc,spans,emg_grd), electrodes, 'UniformOutput', false); 
            
            nroma = max(cellfun(@(sta) max([max(sta),abs(min(sta))]), stas));
            stas_norm = cellfun(@(sta) sta.*(1/nroma), stas, 'UniformOutput', false);
            
            fig = figure;
            set(gcf, 'Color', 'w', 'WindowState', 'maximized');
            t = tiledlayout(max(grid_order(:,1)), max(grid_order(:,2)), 'TileSpacing', 'tight', 'Padding', 'none'); % Create grid
            
            % Use arrayfun to iterate over indices and plot each signal
            arrayfun(@(elc) plotTile(elc, grid_order, stas_norm), electrodes);
            
                % Define the function for plotting
                function plotTile(elc, grid_order, stas_norm)
                    ls = [0.05:1/max(grid_order(:,2)):1.05-1/max(grid_order(:,2))];
                    us = sort([0:1/max(grid_order(:,1)):1-1/max(grid_order(:,1))],'descend');
                    ax = subplot("Position", [ls(grid_order(elc,2)) us(grid_order(elc,1)) 1/max(grid_order(:,2)) 1/max(grid_order(:,1))*0.5]);
                    
                    
                    pos     = get(ax, 'Position');
                    newax   = [pos(1) pos(2) pos(3)*0.8 pos(4)*2];%us(grid_order(elc,1))
                    plot(stas_norm{elc}, 'LineWidth', 2);
                    ylim([-1 1]);
                    axis off;
                    set(ax, 'Position',newax);
                end%function
                
                function stas = stas_per_electrode(elc,spans,emg_signal)        
                        sigFs = cellfun(@(spa) emg_signal(elc,spa), spans, 'UniformOutput', false);
                        stas  = mean(cell2mat(sigFs'),1);
                end%function
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: tabs
        function tabsValueChanged(app, event)
            if isequal(app.tabs.Value, 'DECOMPOSITION')
                app.Panel.Visible = 'on';
                app.Panel_4.Visible = 'off';
                app.Panel_3.Visible = 'off';
                app.Panel_2.Visible = 'on';
            else
                app.Panel.Visible = 'off';
                app.Panel_4.Visible = 'on';
                app.Panel_3.Visible = 'on';
                app.Panel_2.Visible = 'off';
            end
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            app.UIFigure.Visible = 'off'; 
            [app.filename,app.pathname] = uigetfile('*.*');
            app.UIFigure.Visible = 'on'; 
            app.EditField_saving_3.Value = app.filename;

            app.SelectfileButton.BackgroundColor = [0.15 0.15 0.15];
            app.SetconfigurationButton.BackgroundColor = [0.15 0.15 0.15];
            app.SegmentsessionButton.BackgroundColor = [0.15 0.15 0.15];
            app.StartButton.BackgroundColor = [0.15 0.15 0.15];
            app.EditField.Value = ' ';

            % Load files
            C = strsplit(app.filename,'.');
            if isequal(C{end}, 'mat')
                file = load([app.pathname, app.filename]);
                signal = file.signal;
                app.SetconfigurationButton.Enable = 'off';
            elseif isequal(C{end}, 'otb+') % OT Biolab+
                [app.MUdecomp.config, signal] = openOTBplus(app.pathname, app.filename, 1);
                if ~isempty(app.MUdecomp.config)
                    app.MUdecomp.config.UIFigure.Visible = 'off';
                    movegui(app.MUdecomp.config.UIFigure, 'center')
                    app.SetconfigurationButton.Enable = 'on';
                end
            elseif isequal(C{end}, 'otb4') % OTB4
                %[app.MUdecomp.config, signal] = OpenOTB4(app.pathname, app.filename, 0);
                [app.MUdecomp.config, signal] = OpenOTB4_nottingham(app.pathname, app.filename, 0);
                if ~isempty(app.MUdecomp.config)
                    app.MUdecomp.config.UIFigure.Visible = 'off';
                    movegui(app.MUdecomp.config.UIFigure, 'center')
                    app.SetconfigurationButton.Enable = 'on';
                end
            elseif isequal(C{end}, 'rhd') % RHD Intan Tech
                [app.MUdecomp.config, signal] = openIntan(app.pathname, app.filename, 1);
                if ~isempty(app.MUdecomp.config)
                    app.MUdecomp.config.UIFigure.Visible = 'off';
                    movegui(app.MUdecomp.config.UIFigure, 'center')
                    app.SetconfigurationButton.Enable = 'on';
                end
            elseif isequal(C{end}, 'oebin') % Open Ephys GUI
                [app.MUdecomp.config, signal] = openOEphys(app.pathname, app.filename, 1);
                if ~isempty(app.MUdecomp.config)
                    app.MUdecomp.config.UIFigure.Visible = 'off';
                    movegui(app.MUdecomp.config.UIFigure, 'center')
                    app.SetconfigurationButton.Enable = 'on';
                end
            end
            app.ReferenceDropDown.Items = {};
            
            % Update the list of signals for reference
            if isfield(signal,'auxiliaryname')
                app.ReferenceDropDown.Items = cat(2, {'EMG amplitude'}, signal.auxiliaryname);
            elseif isfield(signal,'target')
                signal.auxiliary = [signal.path; signal.target];
                signal.auxiliaryname = cat(2, {'Path'}, {'Target'});
                app.ReferenceDropDown.Items = cat(2, {'EMG amplitude'}, signal.auxiliaryname);
            else
                app.ReferenceDropDown.Items = {'EMG amplitude'};
            end
            
            savename = [app.pathname app.filename '_decomp.mat'];
            save(savename, 'signal', '-v7.3');
            app.SelectfileButton.BackgroundColor = [0.5 0.5 0.5];
            app.EditField.Value = 'Data loaded';
        end

        % Button pushed function: SetconfigurationButton
        function SetconfigurationButtonPushed(app, event)
            app.MUdecomp.config.pathname.Value = [app.pathname app.filename '_decomp.mat'];
            app.MUdecomp.config.UIFigure.Visible = 'on';
            app.SetconfigurationButton.BackgroundColor = [0.5 0.5 0.5];
        end

        % Button pushed function: SegmentsessionButton
        function SegmentsessionButtonPushed(app, event)
            app.MUdecomp.config = segmentsession;
            movegui(app.MUdecomp.config.UIFigure, 'center')
            app.MUdecomp.config.pathname.Value = [app.pathname app.filename '_decomp.mat'];
            app.MUdecomp.config.ReferenceDropDown.Items = {};
            app.MUdecomp.config.ReferenceDropDown.Items = app.ReferenceDropDown.Items;
            app.MUdecomp.config.ReferenceDropDown.Value = app.ReferenceDropDown.Value;
            app.SegmentsessionButton.BackgroundColor = [0.5 0.5 0.5];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.UIAxes_Decomp_2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes_Decomp_2.YColor = [0.9412 0.9412 0.9412];
            
            % No = Consider all the channels ; Yes = Visual checking
            if isequal(app.CheckEMGDropDown.Value, 'Yes')
                parameters.checkEMG = 1;
            else
                parameters.checkEMG = 0;
            end

            % (update the residual EMG by removing the motor units with the highest SIL value)
            if isequal(app.PeeloffDropDown.Value, 'Yes')
                parameters.peeloff = 1;
            else
                parameters.peeloff = 0;
            end

            % (filter out the motor units with a coefficient of variation of their ISI > than parameters.covthr)
            if isequal(app.CoVfilterDropDown.Value, 'Yes')
                parameters.covfilter = 1;
            else
                parameters.covfilter = 0;
            end

            % (realign the discharge time with the peak of the MUAP (channel with the MUAP with the highest p2p amplitude from double diff EMG signal)
            if isequal(app.InitialisationDropDown.Value, 'EMG max')
                parameters.initialization = 1;
            else
                parameters.initialization = 0;
            end
            
            % (refine the MU spike train over the entire signal 1-remove the discharge times that generate outliers in the discharge rate and 2- reevaluate the MU pulse train)
            if isequal(app.RefineMUsDropDown.Value, 'Yes')
                parameters.refineMU = 1;
            else
                parameters.refineMU = 0;
            end
            
            parameters.NITER = app.NumberofiterationsEditField_2.Value; % number of iteration for each grid
            parameters.nwindows = app.NumberofwindowsEditField.Value; % number of segmented windows over each contraction
            parameters.thresholdtarget = app.ThresholdtargetEditField.Value; % threshold to segment the target displayed to the participant, 1 being the maxima of the target (e.g., plateau)
            parameters.nbextchan = app.NbofextendedchannelsEditField.Value; % nb of extended channels (1000 in Negro 2016, can be higher to improve the decomposition)
            parameters.duplicatesthresh = app.DuplicatethresholdEditField.Value; % threshold that define the minimal percentage of common discharge times between duplicated motor units
            parameters.silthr = app.SILthresholdEditField.Value; % Threshold for SIL values
            parameters.covthr = app.COVthresholdEditField.Value; % Threshold for CoV of ISI values
            parameters.CoVDR = 0.3; % threshold that define the CoV of Discharge rate that we want to reach for cleaning the MU discharge times when refineMU is on
            parameters.edges = 0.5; % edges of the signal to remove after preprocessing the signal (in sec)
            parameters.contrastfunc = app.ContrastfunctionDropDown.Value; % contrast functions: 'skew', 'kurtosis', 'logcosh'
            parameters.peeloffwin = 0.025; % duration of the window (s) for detecting the action potentials from the EMG signal
            
            % Step 0: Load the HDsEMG data
            % 0a: determine the number and type of grids
            file = load([app.pathname app.filename '_decomp.mat']);
            signal = file.signal;
            
            [signal.coordinates, signal.IED, signal.EMGmask, signal.emgtype] = formatsignalHDEMG(signal.data, signal.gridname, signal.fsamp, parameters.checkEMG);
            arraynb = zeros(size(signal.data,1),1);
            ch1 = 1;
            for i = 1:signal.ngrid
                arraynb(ch1:ch1+length(signal.EMGmask{i})-1) = i;
                ch1 = ch1+length(signal.EMGmask{i});
            end

            if contains(app.ReferenceDropDown.Value, 'Target')
                signalprocess.ref_signal = signal.target;
                signalprocess.coordinatesplateau = segmenttargets(signalprocess.ref_signal, parameters.nwindows, parameters.thresholdtarget);
                for nwin = 1:length(signalprocess.coordinatesplateau)/2
                    for i = 1:signal.ngrid
                        signalprocess.data{i,nwin} = signal.data(arraynb==i, signalprocess.coordinatesplateau(nwin*2-1):signalprocess.coordinatesplateau(nwin*2));
                        signalprocess.data{i,nwin}(signal.EMGmask{i} == 1,:) = [];
                    end
                end
            elseif contains(app.ReferenceDropDown.Value, 'EMG amplitude')
                tmp = zeros(floor(size(signal.data,1)/2), size(signal.data,2));
                for i = 1:floor(size(signal.data,1)/2)
                    tmp(i,:) = movmean(abs(signal.data(i,:)), signal.fsamp);
                end
                signalprocess.ref_signal = mean(tmp,1);
                signal.target = signalprocess.ref_signal;
                signal.path = signalprocess.ref_signal;
                plot(app.UIAxes_Decomp_2, tmp', 'Color', [0.5 0.5 0.5], 'LineWidth', 0.25)
                hold(app.UIAxes_Decomp_2, 'on')
                plot(app.UIAxes_Decomp_2, signalprocess.ref_signal, 'Color', [0.85 0.33 0.10], 'LineWidth', 2)
                app.UIAxes_Decomp_2.XColor = [0.9412 0.9412 0.9412];
                app.UIAxes_Decomp_2.YColor = [0.9412 0.9412 0.9412];
                app.UIAxes_Decomp_2.YLim = [0 max(signalprocess.ref_signal)*1.5];
                clearvars tmp
                hold(app.UIAxes_Decomp_2, 'off')
                for nwin = 1:parameters.nwindows
                    app.EditField.Value = ['EMG amplitude for 50% of the EMG channels - Select the window #' num2str(nwin)];
                    app.roi = drawrectangle(app.UIAxes_Decomp_2, 'DrawingArea', 'auto');
                    x = [app.roi.Position(1) app.roi.Position(1) + app.roi.Position(3)];
                    x = sort(x,'ascend');
                    x(x<1) = 1;
                    x(x>length(signalprocess.ref_signal)) = length(signalprocess.ref_signal);
                    signalprocess.coordinatesplateau(nwin*2-1) = floor(x(1));
                    signalprocess.coordinatesplateau(nwin*2) = floor(x(2));
                    for i = 1:signal.ngrid
                        signalprocess.data{i,nwin} = signal.data(arraynb==i, signalprocess.coordinatesplateau(nwin*2-1):signalprocess.coordinatesplateau(nwin*2));
                        signalprocess.data{i,nwin}(signal.EMGmask{i} == 1,:) = [];
                    end
                end
                clearvars x
            else
                for i = 1:length(signal.auxiliaryname)
                    if contains(app.ReferenceDropDown.Value, signal.auxiliaryname{i})
                        idx = i;
                    end
                end
                signalprocess.ref_signal = signal.auxiliary(idx,:);
                signalprocess.ref_signal = (signalprocess.ref_signal-signalprocess.ref_signal(1))/max((signalprocess.ref_signal-signalprocess.ref_signal(1)));
                signal.target = signalprocess.ref_signal;
                signal.path = signalprocess.ref_signal;
                plot(app.UIAxes_Decomp_2, signalprocess.ref_signal, 'Color', [0.5 0.5 0.5], 'LineWidth', 2)
                app.UIAxes_Decomp_2.XColor = [0.9412 0.9412 0.9412];
                app.UIAxes_Decomp_2.YColor = [0.9412 0.9412 0.9412];
                app.UIAxes_Decomp_2.YLim = [1 length(signal.target)];
                app.UIAxes_Decomp_2.YLim = [0 max(signalprocess.ref_signal)*1.2];
                hold(app.UIAxes_Decomp_2, 'off')
                for nwin = 1:parameters.nwindows
                    app.EditField.Value = [signal.auxiliaryname{idx} ' - Select the window #' num2str(nwin)];
                    app.roi = drawrectangle(app.UIAxes_Decomp_2, 'DrawingArea', 'auto');
                    x = [app.roi.Position(1) app.roi.Position(1) + app.roi.Position(3)];
                    x = sort(x,'ascend');
                    x(x<1) = 1;
                    x(x>length(signalprocess.ref_signal)) = length(signalprocess.ref_signal);
                    signalprocess.coordinatesplateau(nwin*2-1) = floor(x(1));
                    signalprocess.coordinatesplateau(nwin*2) = floor(x(2));
                    for i = 1:signal.ngrid
                        signalprocess.data{i,nwin} = signal.data(arraynb==i, signalprocess.coordinatesplateau(nwin*2-1):signalprocess.coordinatesplateau(nwin*2));
                        signalprocess.data{i,nwin}(signal.EMGmask{i} == 1,:) = [];
                    end
                end
                clearvars x idx
            end
            
            signalprocess.time = linspace(0,length(signalprocess.ref_signal)/signal.fsamp,length(signalprocess.ref_signal));

            app.UIAxes_Decomp_1.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes_Decomp_1.YColor = [0.9412 0.9412 0.9412];

            for i = 1:signal.ngrid
            for nwin = 1:length(signalprocess.coordinatesplateau)/2
            
            % Step 1: Preprocessing
            %       1a: Removing line interference (Notch filter)
                f = waitbar(0.2, ['Array #' num2str(i) ' - Preprocessing - Filtering EMG signals']);
                signalprocess.data{i,nwin} = notchsignals(signalprocess.data{i,nwin},signal.fsamp);
            %       1b: Bandpass filtering
                signalprocess.data{i,nwin} = bandpassingals(signalprocess.data{i,nwin},signal.fsamp, signal.emgtype(i));
                        
            %       1c: Signal extension (extension factor calculated to reach 1000
            %       channels)
                waitbar(0.4, f, ['Array #' num2str(i) ' - Preprocessing - Extending EMG signals'])
            
                signalprocess.exFactor = round(parameters.nbextchan/size(signalprocess.data{i,nwin},1));
                signalprocess.ReSIG = zeros(signalprocess.exFactor * size(signalprocess.data{i,nwin},1));
                signalprocess.iReSIG{nwin} = zeros(signalprocess.exFactor * size(signalprocess.data{i,nwin},1));
                
                signalprocess.eSIG{nwin} = extend(signalprocess.data{i,nwin},signalprocess.exFactor);
                signalprocess.ReSIG = signalprocess.eSIG{nwin} * signalprocess.eSIG{nwin}' / size(signalprocess.eSIG{nwin},2);
                signalprocess.iReSIG{nwin} = pinv(signalprocess.ReSIG);
            
            %       1d: Removing the mean
                signalprocess.eSIG{nwin} = demean(signalprocess.eSIG{nwin});
            
            % Step 2: Whitening
            %Whitening with a regularization factor (average of the smallest half of 
            %the eigenvalues of the covariance matrix from the extended signals)
            
            %       2a: Get eigenvalues and eigenvectors (regularization factor =>
            %       average smallest half of eigenvalues)
                waitbar(0.6, f, ['Array #' num2str(i) ' - Preprocessing - Whitening EMG signals'])
                [E, D] = pcaesig(signalprocess.eSIG{nwin}); %Returns the eigenvector (E) and diagonal eigenvalue (D) matrices
            
            %       2b: Zero-phase component analysis
                [signalprocess.wSIG{nwin}, ~, ~] = whiteesig(signalprocess.eSIG{nwin}, E, D);
                clearvars E D
            
            % Remove the edges
                signalprocess.eSIG{nwin} = signalprocess.eSIG{nwin}(:,round(signal.fsamp*parameters.edges):end-round(signal.fsamp*parameters.edges));
                signalprocess.wSIG{nwin} = signalprocess.wSIG{nwin}(:,round(signal.fsamp*parameters.edges):end-round(signal.fsamp*parameters.edges));
            
                if i == 1
                    signalprocess.coordinatesplateau(nwin*2-1) = signalprocess.coordinatesplateau(nwin*2-1) + round(signal.fsamp*parameters.edges)-1;
                    signalprocess.coordinatesplateau(nwin*2) = signalprocess.coordinatesplateau(nwin*2) - round(signal.fsamp*parameters.edges);
                end
            
            % Step 3: FastICA method
            
            waitbar(0.8, f, ['Array #' num2str(i) ' - Decomposition - Decomposing EMG signals'])
            
            % Initialize matrix B (n x m) n: separation vectors, m: iterations 
            % Initialize matrix MUFilters to only save the reliable filters
            % Intialize SIL and PNR
            
            signalprocess.B = zeros(size(signalprocess.wSIG{nwin},1), parameters.NITER); % all separation vectors
            signalprocess.MUFilters{nwin} = zeros(size(signalprocess.wSIG{nwin},1), parameters.NITER); % only reliable vectors
            signalprocess.w = zeros(size(signalprocess.wSIG{nwin},1), 1);
            signalprocess.icasig = zeros(parameters.NITER, size(signalprocess.wSIG{nwin},2));
            signalprocess.SIL{nwin} = zeros(1, parameters.NITER);
            signalprocess.CoV{nwin} = zeros(1, parameters.NITER);
            idx1 = zeros(1, parameters.NITER);
            
            % Find the index where the square of the summed whitened vectors is
            % maximized and initialize W with the whitened observations at this time
            
            close(f)
                        
            for j = 1:parameters.NITER
            if j == 1
                signalprocess.X = signalprocess.wSIG{nwin}; % Initialize X (whitened signal), then X: residual
                
                if parameters.initialization == 1
                    actind = sum(signalprocess.X,1).^2;
                    [~, idx1(j)] = max(actind);
                    signalprocess.w = signalprocess.X(:, idx1(j)); % Initialize w
                else
                    signalprocess.w = randn(size(signalprocess.X,1),1); % Initialize w
                end
                signalprocess.time2 = linspace(signalprocess.time(signalprocess.coordinatesplateau(nwin*2-1)),signalprocess.time(signalprocess.coordinatesplateau(nwin*2)),size(signalprocess.X,2));
            else
                if parameters.initialization == 1
                    actind(idx1(j-1)) = 0; % remove the previous vector
                    [~, idx1(j)] = max(actind);
                    signalprocess.w = signalprocess.X(:, idx1(j)); % Initialize w
                else
                    signalprocess.w = randn(size(signalprocess.X,1),1); % Initialize w
                end
            end
            
            signalprocess.w = signalprocess.w - signalprocess.B * signalprocess.B' * signalprocess.w; % Orthogonalization
            signalprocess.w = signalprocess.w / norm(signalprocess.w); % Normalization
            
            %       3a: Fixed point algorithm (end when sparsness is maximized)
            maxiter = 500; % max number of iterations for the fixed point algorithm
            signalprocess.w = fixedpointalg(signalprocess.w, signalprocess.X, signalprocess.B , maxiter, parameters.contrastfunc);
            
            % Step 4: Minimization of the CoV of discharge times (end when CoV is
            % minimized)
            
            % Initialize CoV (variation of interspike intervals, %) Step 4a => 4e
            [signalprocess.icasig, signalprocess.spikes] = getspikes(signalprocess.w, signalprocess.X, signal.fsamp);
            
            if length(signalprocess.spikes) > 10
                ISI = diff(signalprocess.spikes/signal.fsamp); % Interspike interval
                signalprocess.CoV{nwin}(j) = std(ISI)/mean(ISI); % Coefficient of variation
                Wini = sum(signalprocess.X(:,signalprocess.spikes),2); % update W by summing the spikes
                               
                        % Minimization of the CoV of discharge times (end when CoV is
                % minimized)
                [signalprocess.MUFilters{nwin}(:,j), signalprocess.spikes, signalprocess.CoV{nwin}(j)] = minimizeCOVISI(Wini, signalprocess.X, signalprocess.CoV{nwin}(j), signal.fsamp);
                signalprocess.B(:,j) = signalprocess.w;
            
                % Calculate SIL values
                [signalprocess.icasig, signalprocess.spikes, signalprocess.SIL{nwin}(j)] = calcSIL(signalprocess.X, signalprocess.MUFilters{nwin}(:,j), signal.fsamp);
                
                % Peel-off of the (reliable) source    
                if parameters.peeloff == 1 && signalprocess.SIL{nwin}(j) > parameters.silthr
                   signalprocess.X = peeloff(signalprocess.X, signalprocess.spikes, signal.fsamp, parameters.peeloffwin);
                end
            
                plot(app.UIAxes_Decomp_2, signalprocess.time, signal.target, 'k--', 'LineWidth', 2, 'Color', [0.9412 0.9412 0.9412])
                line(app.UIAxes_Decomp_2,[signalprocess.time(signalprocess.coordinatesplateau(nwin*2-1)) signalprocess.time(signalprocess.coordinatesplateau(nwin*2-1))],[0 max(signal.target)], 'Color', [0.85 0.33 0.10], 'LineWidth', 2)
                line(app.UIAxes_Decomp_2,[signalprocess.time(signalprocess.coordinatesplateau(nwin*2)) signalprocess.time(signalprocess.coordinatesplateau(nwin*2))],[0 max(signal.target)], 'Color', [0.85 0.33 0.10], 'LineWidth', 2)
                app.EditField.Value = ['Array #' num2str(i) ' - Iteration #' num2str(j) ' - Sil = ' num2str(signalprocess.SIL{nwin}(j)) ' CoV = ' num2str(signalprocess.CoV{nwin}(j))];
                plot(app.UIAxes_Decomp_1,signalprocess.time2,signalprocess.icasig,signalprocess.time2(signalprocess.spikes),signalprocess.icasig(signalprocess.spikes),'o', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412], 'MarkerSize', 10, 'MarkerEdgeColor', [0.85 0.33 0.10]);
                app.UIAxes_Decomp_1.YLim = [-0.2 1.5];
                drawnow;
            else
                signalprocess.B(:,j) = signalprocess.w;
                app.EditField.Value = ['Array #' num2str(i) ' - Iteration #' num2str(j)];
                plot(app.UIAxes_Decomp_1,signalprocess.time2,signalprocess.icasig,signalprocess.time2(signalprocess.spikes),signalprocess.icasig(signalprocess.spikes),'o', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412], 'MarkerSize', 10, 'MarkerEdgeColor', [0.85 0.33 0.10]);
                drawnow;
            end
            end
                        
            % Filter out MUfilters below the SIL threshold
            signalprocess.MUFilters{nwin}(:,signalprocess.SIL{nwin} < parameters.silthr) = [];
            if parameters.covfilter == 1
                signalprocess.CoV{nwin}(signalprocess.SIL{nwin} < parameters.silthr) = [];
                signalprocess.MUFilters{nwin}(:,signalprocess.CoV{nwin} > parameters.covthr) = [];
            end
            
            end
            
            f = waitbar(0.8,['Array #' num2str(i) ' - Postprocessing']);
            
            % Batch processing over each window
            [PulseT, distime] = batchprocessfilters(signalprocess.MUFilters, signalprocess.wSIG, signalprocess.coordinatesplateau, signalprocess.exFactor, 0, size(signal.data,2), signal.fsamp);
            
            if size(PulseT,1) > 0
            % Remove duplicates remduplicates(app.MUedition.edition.Pulsetrainclean{i}, app.MUedition.edition.Distimeclean{i}, app.MUedition.edition.Distimeclean{i}, round(app.MUedition.signal.fsamp/40), 0.00025, app.DuplicatethresholdEditField.Value, app.MUedition.signal.fsamp)
                [PulseT, distimenew] = remduplicates(PulseT, distime, distime, round(signal.fsamp/40), 0.00025, parameters.duplicatesthresh, signal.fsamp);
                
                if parameters.refineMU == 1    
                    % Remove outliers generating irrelevant discharge rates before manual
                    % edition (1st time)
                    distimenew = remoutliers(PulseT, distimenew, parameters.CoVDR, signal.fsamp);
                
                    % Reevaluate all the unique motor units over the contractions
                    [signal.Pulsetrain{i}, distimenew] = refineMUs(signal.data(arraynb==i, :), signal.EMGmask{i}, PulseT, distimenew, signal.fsamp);
                    
                    % Remove outliers generating irrelevant discharge rates before manual
                    % edition (2nd time)
                    distimenew = remoutliers(signal.Pulsetrain{i}, distimenew, parameters.CoVDR, signal.fsamp);
                else
                    signal.Pulsetrain{i} = PulseT;
                end
                
                % Save the results
                for j = 1:length(distimenew)
                    signal.Dischargetimes{i,j} = distimenew{j};
                end
            end
            
            close(f);
            end

            app.EditField.Value = 'Saving data';
            % Save file
            clearvars signalprocess i j PulseT distime distimenew actind idx1 time ISI CoV maxiter nwin Wini temp
            savename = [app.pathname app.filename '_decomp.mat'];
            save(savename, 'signal', 'parameters', '-v7.3');
            app.EditField.Value = 'Data saved';
            app.StartButton.BackgroundColor = [0.5 0.5 0.5];
        end

        % Button pushed function: SelectfileButton_2
        function SelectfileButton_2Pushed(app, event)
            app.UIFigure.Visible = 'off'; 
            [app.filename2,app.pathname2] = uigetfile;
            app.UIFigure.Visible = 'on'; 
            app.EditField_saving_2.Value = app.filename2;
        end

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            app.MUdisplayedDropDown.Items = {};
            
            % Load the file
            if contains(app.filename2,'edited')
                files = load([app.pathname2 app.filename2], 'parameters', 'signal', 'edition'); 
                for i = 1:size(files.edition.Pulsetrain,2)
                    % Update the list and load the edited files
                    for j = 1:size(files.edition.Pulsetrain{i},1)
                        app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Array_', num2str(i), '_MU_' , num2str(j)]});
                        if length(files.edition.Dischargetimes{i,j}) > 2
                            files.edition.silval{i,j} = getsil(files.edition.Pulsetrain{i}(j,:), files.signal.fsamp);
                            files.edition.silvalcon{i,j} = refinesil(files.edition.Pulsetrain{i}(j,:), files.edition.Dischargetimes{i,j}, files.signal.fsamp);
                        else
                            files.edition.silval{i,j} = 0;
                        end

                    end
                end
                app.MUdisplayedDropDown.Enable = 'on';
            else
                files = load([app.pathname2 app.filename2], 'parameters', 'signal');   
                for i = 1:size(files.signal.Pulsetrain,2)
                    % Update the list and load the edited files
                    files.edition.Pulsetrain{i} = files.signal.Pulsetrain{i};
                    for j = 1:size(files.signal.Pulsetrain{i},1)
                        files.edition.Dischargetimes{i,j} = files.signal.Dischargetimes{i,j};
                        app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Array_', num2str(i), '_MU_' , num2str(j)]});

                        if length(files.edition.Dischargetimes{i,j}) > 2
                            files.edition.silval{i,j} = getsil(files.signal.Pulsetrain{i}(j,:), files.signal.fsamp);
                            files.edition.silvalcon{i,j} = refinesil(files.signal.Pulsetrain{i}(j,:), files.signal.Dischargetimes{i,j}, files.signal.fsamp);
                        else
                            files.edition.silval{i,j} = 0;
                        end
                        files.edition.time = linspace(0,size(files.signal.Pulsetrain{i},2)/files.signal.fsamp, size(files.signal.Pulsetrain{i},2));
                    end
                end
                app.MUdisplayedDropDown.Enable = 'on';
            end

            files.edition.arraynb = zeros(size(files.signal.data,1),1);
            ch1 = 1;
            for i = 1:files.signal.ngrid
                files.edition.arraynb(ch1:ch1+length(files.signal.EMGmask{i})-1) = i;
                ch1 = ch1+length(files.signal.EMGmask{i});
            end

            % Update the list of references with Auxiliary data
            app.ReferenceDropDown_2.Items = {};
            if isfield(files.signal, 'auxiliary')
                uniqueNames = files.signal.auxiliaryname;
                % Append index to duplicate names
                % this gets around the duplicate names like Aux
                % acceleration
                for i = 1:length(uniqueNames)
                    uniqueNames{i} = [uniqueNames{i} '_' num2str(i)];
                end
                app.ReferenceDropDown_2.Items = uniqueNames;
                app.ReferenceDropDown_2.Value = uniqueNames{1};
                files.signal.target = files.signal.auxiliary(1,:);
                files.signal.auxiliaryname = uniqueNames; % keep in sync

                % app.ReferenceDropDown_2.Items = files.signal.auxiliaryname;
                % app.ReferenceDropDown_2.Value = files.signal.auxiliaryname{1};
                % files.signal.target = files.signal.auxiliary(1,:);
            elseif isfield(files.signal, 'target') && isfield(files.signal, 'path')
                app.ReferenceDropDown_2.Items = {'target', 'path', 'EMG amplitude'};
                files.signal.target_orig    = files.signal.target;
                files.signal.path_orig      = files.signal.path;
                
            end
            
            % Display the first MU
            C = strsplit(app.MUdisplayedDropDown.Value,'_');

            plot(app.UIAxesSpiketrain, files.edition.time, files.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, files.edition.time(files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), files.edition.Pulsetrain{str2double(C{2})}(1,files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            plot(app.UIAxesSpiketrain, files.edition.time, files.signal.target/max(files.signal.target), '--', 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);

            hold(app.UIAxesSpiketrain, 'off')
            app.UIAxesSpiketrain.XColor = [0.9412 0.9412 0.9412];
            app.UIAxesSpiketrain.YColor = [0.9412 0.9412 0.9412];

            distime = zeros(1,length(files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime(i) = (round((files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / files.signal.fsamp;
            end
            DR = 1./((diff(files.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/files.signal.fsamp);

            plot(app.UIAxesDR, distime, DR, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);

            app.EditField_2.Value = ['SIL = ' num2str(files.edition.silval{1,1})];

            if app.SILCheckBox.Value == 1
                bar(app.UIAxesSIL, files.edition.time(files.edition.silvalcon{1,1}(:,1)), files.edition.silvalcon{1,1}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                app.UIAxesSIL.XLim = [files.edition.time(1) files.edition.time(end)];
                app.UIAxesSIL.YLim = [0.5 1];
                app.UIAxesSIL.XAxis.Visible = 'Off';
                app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412];
            end

            app.UIAxesSpiketrain.XLim = [files.edition.time(1) files.edition.time(end)];
            app.UIAxesDR.XLim = [files.edition.time(1) files.edition.time(end)];
            app.UIAxesDR.YLim = [0 max(DR)*1.5];


            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesDR.XColor = [0.9412 0.9412 0.9412];
            app.UIAxesDR.YColor = [0.9412 0.9412 0.9412];
            app.graphstart = files.edition.time(1);
            app.graphend = files.edition.time(end);

            app.MUedition = files;
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};
            app.Backup.lock = 0;
            
            app.Flagged=[];
            if ~app.ClearflaggedcacheCheckBox.Value
                try
                    finput = load([app.pathname2 app.filename2], 'flagged');
                    app.Flagged = finput.flagged;
                catch
                    disp('there is no "flagged" variable yet, a new empty one will be created')
                    app.Flagged = [];
                end%try
            end%if
            
        end

        % Value changed function: MUdisplayedDropDown
        function MUdisplayedDropDownValueChanged(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            distime = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end

            if ~isempty(distime)
                DR = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime, DR, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
                app.UIAxesDR.YLim = [0 max(DR)*1.5];
            end

            app.EditField_2.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];
            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];

            if app.SILCheckBox.Value == 1
                bar(app.UIAxesSIL, app.MUedition.edition.time(app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,1)), app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                app.UIAxesSIL.XLim = [app.graphstart app.graphend];
                app.UIAxesSIL.YLim = [0.5 1];
                app.UIAxesSIL.XAxis.Visible = 'Off';
                app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412];
            end
            
            if any(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:)~=0)
                app.FlagMUforDropDown.Enable    = "on";
                app.FlagMUforDropDown.Value     = app.FlagMUforDropDown.Placeholder;
            else
                app.FlagMUforDropDown.Enable    = "off";
                app.FlagMUforDropDown.Value     = app.FlagMUforDropDown.Placeholder;
            end
            

        end

        % Value changed function: ReferenceDropDown_2
        function ReferenceDropDown_2ValueChanged(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            thisGrid    = str2double(C{2});
            thisMU      = str2double(C{4});
            if contains(app.ReferenceDropDown_2.Value, 'EMG amplitude')
                tmp = zeros(floor(size(app.MUedition.signal.data,1)/2), size(app.MUedition.signal.data,2));
                for i = 1:floor(size(app.MUedition.signal.data,1)/2)
                    tmp(i,:) = movmean(abs(app.MUedition.signal.data(i,:)), app.MUedition.signal.fsamp);
                end
                app.MUedition.signal.target = mean(tmp,1);
                app.MUedition.signal.path = mean(tmp,1);
                % Update the graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{thisGrid}(thisMU,:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{thisGrid,thisMU}), app.MUedition.edition.Pulsetrain{thisGrid}(thisMU,app.MUedition.edition.Dischargetimes{thisGrid,thisMU}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];

            elseif ~contains('target', app.ReferenceDropDown_2.Items)
                for i = 1:length(app.MUedition.signal.auxiliaryname)
                    if contains(app.ReferenceDropDown_2.Value, app.MUedition.signal.auxiliaryname{i})
                        idx = i;
                    end
                end
                app.MUedition.signal.target = (app.MUedition.signal.auxiliary(idx,:)-app.MUedition.signal.auxiliary(idx,1))/max(app.MUedition.signal.auxiliary(idx,:)-app.MUedition.signal.auxiliary(idx,1));
                app.MUedition.signal.path = (app.MUedition.signal.auxiliary(idx,:)-app.MUedition.signal.auxiliary(idx,1))/max(app.MUedition.signal.auxiliary(idx,:)-app.MUedition.signal.auxiliary(idx,1));
                % Update the graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{thisGrid}(thisMU,:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{thisGrid,thisMU}), app.MUedition.edition.Pulsetrain{thisGrid}(thisMU,app.MUedition.edition.Dischargetimes{thisGrid,thisMU}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];

            elseif contains(app.ReferenceDropDown_2.Value, 'target')
                app.MUedition.signal.target = app.MUedition.signal.target_orig;
                app.MUedition.signal.path   = app.MUedition.signal.target_orig;
                % Update the graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{thisGrid}(thisMU,:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{thisGrid,thisMU}), app.MUedition.edition.Pulsetrain{thisGrid}(thisMU,app.MUedition.edition.Dischargetimes{thisGrid,thisMU}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];

            elseif contains(app.ReferenceDropDown_2.Value, 'path')
                app.MUedition.signal.target = app.MUedition.signal.path_orig;
                app.MUedition.signal.path   = app.MUedition.signal.path_orig;
                % Update the graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{thisGrid}(thisMU,:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{thisGrid,thisMU}), app.MUedition.edition.Pulsetrain{thisGrid}(thisMU,app.MUedition.edition.Dischargetimes{thisGrid,thisMU}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            end
            
        end

        % Value changed function: SILCheckBox
        function SILCheckBoxValueChanged(app, event)
            if app.SILCheckBox.Value == 1
                app.UIAxesDR.Position = [5,492,1089,212];
                app.UIAxesSIL.Visible = 'on';
                C = strsplit(app.MUdisplayedDropDown.Value,'_');
                % Update the graph
                app.EditField_2.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];

                bar(app.UIAxesSIL, app.MUedition.edition.time(app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,1)), app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                app.UIAxesSIL.XAxis.Visible = 'Off';
                app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412]; 
            else
                app.UIAxesDR.Position = [5,492,1089,290];
                cla(app.UIAxesSIL)
                app.UIAxesSIL.Visible = 'off';
            end
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            center = app.graphstart + duration/2;
            duration = duration * 0.8;
            app.graphstart = center - duration/2;
            app.graphend = center + duration/2;
            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesSIL.XLim = [app.graphstart app.graphend];
            app.UIAxesSIL.YLim = [0.5 1];
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            center = app.graphstart + duration/2;
            duration = duration * 1.5;
            if duration > (app.MUedition.edition.time(end) - app.MUedition.edition.time(1))
                app.graphstart = app.MUedition.edition.time(1);
                app.graphend = app.MUedition.edition.time(end);
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSIL.XLim = [app.graphstart app.graphend];
                app.UIAxesSIL.YLim = [0.5 1];
            else
                app.graphstart = center - duration/2;
                app.graphend = center + duration/2;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSIL.XLim = [app.graphstart app.graphend];
                app.UIAxesSIL.YLim = [0.5 1];
            end
        end

        % Button pushed function: ScrollleftButton
        function ScrollleftButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            step = 0.05 * duration;
            if (app.graphstart - step) < 0
                app.graphstart = 0;
                app.graphend = app.graphstart + duration;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSIL.XLim = [app.graphstart app.graphend];
                app.UIAxesSIL.YLim = [0.5 1];
            else
                app.graphstart = app.graphstart - step;
                app.graphend = app.graphstart + duration;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSIL.XLim = [app.graphstart app.graphend];
                app.UIAxesSIL.YLim = [0.5 1];
            end
        end

        % Button pushed function: ScrollrightButton
        function ScrollrightButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            step = 0.05 * duration;
            if (app.graphend + step) > app.MUedition.edition.time(end)
                app.graphend = app.MUedition.edition.time(end);
                app.graphstart = app.graphend - duration;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSIL.XLim = [app.graphstart app.graphend];
                app.UIAxesSIL.YLim = [0.5 1];
            else
                app.graphend = app.graphend + step;
                app.graphstart = app.graphend - duration;
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSIL.XLim = [app.graphstart app.graphend];
                app.UIAxesSIL.YLim = [0.5 1];
            end
        end

        % Callback function: AddspikesButton, UIAxes_Decomp_2
        function AddspikesButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};
            
            app.roi = drawrectangle(app.UIAxesSpiketrain);
            y = [app.roi.Position(2) app.roi.Position(2) + app.roi.Position(4)];
            x = [app.roi.Position(1) app.roi.Position(1) + app.roi.Position(3)];

            temp = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:);
            temp(x(1)> app.MUedition.edition.time | app.MUedition.edition.time>x(2)) = 0;
            [~, locs] = findpeaks(temp,'MinPeakHeight', y(1), 'MinPeakDistance', round(0.005*app.MUedition.signal.fsamp));
            delete(app.roi)

            if ~isempty(locs)
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = [app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} locs];
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = unique(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = sort(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});
                
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
    
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end
        end

        % Button pushed function: DeletespikesButton
        function DeletespikesButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};
            
            app.roi = drawrectangle(app.UIAxesSpiketrain);
            y = [app.roi.Position(2) app.roi.Position(2) + app.roi.Position(4)];
            x = [app.roi.Position(1) app.roi.Position(1) + app.roi.Position(3)];

            idx = find(x(1)< app.MUedition.edition.time & app.MUedition.edition.time<x(2));
            idx2t1 = find(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:) > y(1) & app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:) < y(2));
            idx2t2 = find(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:) > 1.5);
            idx2 = [idx2t1 idx2t2];
            idx3 = intersect(idx,idx2);

            delete(app.roi)

            if ~isempty(idx3)
                idxdel = intersect(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})},idx3);
                for j = 1:length(idxdel)
                    app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} == idxdel(j)) = [];
                end
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end
        end

        % Button pushed function: DeleteDRButton
        function DeleteDRButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};
            
            distime = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
            
            app.roi = drawrectangle(app.UIAxesDR);
            y = [app.roi.Position(2) app.roi.Position(2) + app.roi.Position(4)];
            x = [app.roi.Position(1) app.roi.Position(1) + app.roi.Position(3)];

            idx = find(x(1)<distime & distime<x(2));
            idx2 = find(DR > y(1));
            idx3 = intersect(idx,idx2);
            delete(app.roi)

            idxdel = zeros(1,length(idx3));
            for i = 1:length(idx3)
                if app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idx3(i))) < app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idx3(i)+1))
                    idxdel(i) = idx3(i);
                else
                    idxdel(i) = idx3(i)+1;
                end
            end
            if ~isempty(idx3)
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idxdel) = [];
                
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end
        end

        % Button pushed function: LockspikesButton
        function LockspikesButtonPushed(app, event)
            app.Backup.lock = 1;
            app.LockspikesButton.BackgroundColor = [0.5 0.5 0.5];
        end

        % Callback function
        function FlagMUfordeletionButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};
            app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :) = 0;
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = [1 app.MUedition.signal.fsamp];

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);

            plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);

            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesDR.YLim = [0 max(DR1)*1.5];
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :) = app.Backup.Pulsetrain;
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = app.Backup.Dischargetimes;

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);

            plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);

            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.YLim = [0 max(DR1)*1.5];

            app.FlagMUforDropDown.Enable    = "on";
            app.FlagMUforDropDown.Value     = app.FlagMUforDropDown.Placeholder;
        end

        % Button pushed function: RemoveoutliersButton
        function RemoveoutliersButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};

            distime = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
            thres = mean(DR) + 3*std(DR);
            idx = find(DR > thres);

            idxdel = zeros(1,length(idx));
            for i = 1:length(idx)
                if app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idx(i))) < app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idx(i)+1))
                    idxdel(i) = idx(i);
                else
                    idxdel(i) = idx(i)+1;
                end
            end
            if ~isempty(idx)
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(idxdel) = [];
                
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
    
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end
        end

        % Button pushed function: UpdateMUfilterButton
        function UpdateMUfilterButtonPushed(app, event)
            t = tic;
            
            disp('Updating MU filter...')
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};

            % Update MU filter
            nbextchan = 1000;
            idx = find(app.MUedition.edition.time > app.graphstart & app.MUedition.edition.time < app.graphend);
            
            idxbinary = ones(size(idx));
            if app.IgnorewindowsCheckBox.Value
                ignoreavaliable = 1:numel(app.Flagged.ignoreWindows);
                switch app.IgnorewindowsduringupdateSwitch.Value
                    case 'all'
                        ignore_wds = ignoreavaliable;
                    case 'specific:'
                        ignore_wds = str2num(app.seperatenumbersbycommaEditField_2.Value);
                        ignore_wds = ignore_wds(ismember(ignore_wds,ignoreavaliable));
                end%switch
                nr_wds = length(ignore_wds);
                if nr_wds == 0
                    disp('no ignorewindows selected or avaliable')
                else
                    for wd = 1:nr_wds
                        wd_s_time = app.Flagged.ignoreWindows{ignore_wds(wd)}(1);
                        wd_e_time = app.Flagged.ignoreWindows{ignore_wds(wd)}(2);
                        wd_s = find(app.MUedition.edition.time > wd_s_time, 1,"first");
                        wd_e = find(app.MUedition.edition.time < wd_e_time,1,"last");
                        wd_idx = wd_s:wd_e;
                        
                        idxbinary(ismember(idx,wd_idx)) = 0;
                    end%for
                end%if

            end%if
            idx_orig    = idx;
            % idx_orig2 = idx_orig;%
            idx         = idx(idxbinary==1);
            % idx_orig = idx;%

            edges_len   = round(0.1*app.MUedition.signal.fsamp);
            edgesbinary = zeros(size(idxbinary));
            edgesbinary(find(idxbinary,edges_len, 'first')) = 1;
            edgesbinary(find(idxbinary,edges_len, 'last'))  = 1;
            
            EMG = app.MUedition.signal.data(app.MUedition.edition.arraynb==str2double(C{2}),:);
            EMG = EMG(app.MUedition.signal.EMGmask{str2double(C{2})}==0,idx);
            EMG = bandpassingals(EMG, app.MUedition.signal.fsamp, app.MUedition.signal.emgtype(str2double(C{2})));
            % spikes1 = intersect(idx(round(0.1*app.MUedition.signal.fsamp):end-round(0.1*app.MUedition.signal.fsamp)),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});
            spikes1_noedges = intersect(idx_orig(edgesbinary==0),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});%edges removed edges_len:end-edges_len
            spikes1         = intersect(idx_orig,app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}); %no edges removed
            % spikes2 = (spikes1 - idx(1));
            spikes2 = (spikes1 - idx_orig(1))+1;

            spikesbinary = zeros(size(idxbinary));
            spikesbinary(spikes2)           = 1;
            spikesbinary(edgesbinary==1)    = 0;
            spikesbinary = spikesbinary(idxbinary==1);
            spikes2 = find(spikesbinary);

            exFactor1 = round(nbextchan/size(EMG,1));
            eSIG = extend(EMG,exFactor1);
            ReSIG = eSIG*eSIG'/length(eSIG);
            
            iReSIGt = pinv(ReSIG);
            [E, D] = pcaesig(eSIG);
            [wSIG, ~, dewhiteningMatrix] = whiteesig(eSIG, E, D);
            
            poff_mu = []; poff_only = [];
            if app.UsepeeledoffsignalCheckBox.Value
                if ~isfield(app.Flagged,'peeloffOnly')
                    app.Flagged.peeloffOnly.Pulsetrain          = cell(1,numel(app.MUedition.edition.Pulsetrain));
                    app.Flagged.peeloffOnly.Dischargetimes      = cell(1,numel(app.MUedition.edition.Pulsetrain));
                end%if
                peeloffavaliable = setdiff(1:size(app.MUedition.edition.Dischargetimes,2), ...
                                    unique([find(cellfun(@(mu) isempty(mu), app.MUedition.edition.Dischargetimes(str2double(C{2}),:))), find(cellfun(@(mu) all(ismember([1 app.MUedition.signal.fsamp],mu)), app.MUedition.edition.Dischargetimes(str2double(C{2}),:)))]));
                peeloffavaliable = setdiff(peeloffavaliable, str2double(C{4}));
                peeloffonlyavaliable = 1:numel(app.Flagged.peeloffOnly.Dischargetimes{str2double(C{2})});
                switch app.PeeloffMUofcurrentgridSwitch.Value
                    case 'all'
                        poff_mu     = peeloffavaliable;
                        poff_only   = peeloffonlyavaliable;
                    case 'specific:'
                        poff_mu     = str2num(app.seperatenumbersbycommaEditField.Value);
                        poff_only   = poff_mu(poff_mu>1000);
                        poff_mu     = setdiff(poff_mu, poff_only);
                        poff_only   = poff_only-1000;

                        poff_mu     = poff_mu(ismember(poff_mu,peeloffavaliable));
                        poff_only   = poff_only(ismember(poff_only,peeloffonlyavaliable));

                        if any(~ismember(poff_mu,peeloffavaliable))
                            disp(['Can not peeloff MU# ', num2str(poff_mu(~ismember(poff_mu,peeloffavaliable))), 'please put in MU of this list: ', num2str(peeloffavaliable)])
                        end%if
                        if any(~ismember(poff_only,peeloffonlyavaliable))
                            disp(['Can not peeloff MU# ', num2str(poff_only(~ismember(poff_only,peeloffonlyavaliable))+1000), 'please put in MU of this list: ', num2str(peeloffonlyavaliable+1000)])
                        end%if
                        % if any(poff_mu>numel(app.PeeloffSpikes{str2double(C{2})}))
                        %     poff_mu = [];
                        %     disp(['only ', numel(app.PeeloffSpikes{str2double(C{2})}), ' sources to peeloff avaliable for this grid, please only use numbers smaller than this.'])
                        % end%if
                end%switch
                if ~isempty(poff_mu)
                    for pom = 1:length(poff_mu)
                        po = poff_mu(pom);
                        po_spikes1  = intersect(idx_orig,app.MUedition.edition.Dischargetimes{str2double(C{2}),po});
                        po_spikes   = (po_spikes1 - idx_orig(1))+1;
                        spikesbinary                    = zeros(size(idxbinary));
                        spikesbinary(po_spikes)         = 1;
                        spikesbinary(edgesbinary==1)    = 0;
                        spikesbinary                    = spikesbinary(idxbinary==1);
                        po_spikes                       = find(spikesbinary);
                        if ~isempty(po_spikes)
                            wSIG = peeloff_fast(wSIG, po_spikes, app.MUedition.signal.fsamp, app.MUedition.parameters.peeloffwin);
                        end%if
                    end%for-->pom
                else
                    disp('No sources for peeloff selected (or avaliable) from current edition.')
                end%If
                if ~isempty(poff_only)
                    for pom = 1:length(poff_only)
                        po = poff_only(pom);
                        % po_spikes1  = intersect(idx(round(0.1*app.MUedition.signal.fsamp):end-round(0.1*app.MUedition.signal.fsamp)),app.Flagged.peeloffOnly.Dischargetimes{str2double(C{2})}{po});
                        % po_spikes   = (po_spikes1 - idx(1))+1;
                        po_spikes1  = intersect(idx_orig,app.Flagged.peeloffOnly.Dischargetimes{str2double(C{2})}{po});
                        po_spikes   = (po_spikes1 - idx_orig(1))+1;
                        spikesbinary                    = zeros(size(idxbinary));
                        spikesbinary(po_spikes)         = 1;
                        spikesbinary(edgesbinary==1)    = 0;
                        spikesbinary                    = spikesbinary(idxbinary==1);
                        po_spikes                       = find(spikesbinary);
                        if ~isempty(po_spikes)
                            wSIG = peeloff_fast(wSIG, po_spikes, app.MUedition.signal.fsamp, app.MUedition.parameters.peeloffwin);
                        end%if
                    end%for-->pom
                else
                    disp('No sources for peeloff selected (or avaliable) from peeloff only flagged.')
                end%If
            end%if-->peeloff option
            
            MUFilters = sum(wSIG(:,spikes2),2);
            

            if isempty(poff_mu) & isempty(poff_only)
                Pt = ((dewhiteningMatrix * MUFilters)' * iReSIGt) * eSIG; % Update the pulse train
            else
                Pt = MUFilters'*wSIG;
            end%if
            
            Pt= Pt(1:size(EMG,2));
            % Pt([1:round(0.1*app.MUedition.signal.fsamp) end-round(0.1*app.MUedition.signal.fsamp):end]) = 0; % Remove the edges
            Pt([1:edges_len end-edges_len+1:end]) = 0; % Remove the edges
            Pt = Pt .* abs(Pt); % Normalized and update the pulse train
            
            % put back in original lenght
            Pt_zeros = zeros(size(idxbinary));
            Pt_zeros(idxbinary==1) = Pt;
            Pt = Pt_zeros; clear Pt_zeros

            [~,spikes] = findpeaks(Pt,'MinPeakDistance', round(app.MUedition.signal.fsamp*0.005)); % Peak detection
            Pt = Pt/mean(maxk(Pt(spikes),10));

            [L,C2] = kmeans(Pt(spikes)',2); % Kmean classification
            [~, idx2] = max(C2); % Find the class with the highest centroid
            spikes2 = spikes(L==idx2);
            spikes2(Pt(spikes2)>mean(Pt(spikes2))+3*std(Pt(spikes2))) = []; % remove the outliers of the pulse train for the calculation of the filter

            if app.Backup.lock == 1
                spikeso = spikes1_noedges- idx_orig(1); %original spikes without edges
                for i = 1:length(spikeso)
                   [~, imax] = max(Pt(spikeso(i) - 10: spikeso(i) + 10));
                   spikeso(i) = spikeso(i) + imax - 11;
                end
                spikes2 = setdiff(spikes2, spikeso);
                spikes2 = [spikes2, spikeso];
                app.Backup.lock = 0;
                app.LockspikesButton.BackgroundColor = [0.15,0.15,0.15];
            end
            
            
            prev_edges          =  app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), idx_orig(edgesbinary==1));%the previous edges (before the update)
            Pt(edgesbinary==1)  = prev_edges;

            % app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),idx(1)+round(0.1*app.MUedition.signal.fsamp)-1:idx(end)-round(0.1*app.MUedition.signal.fsamp)) = Pt(round(0.1*app.MUedition.signal.fsamp):end-round(0.1*app.MUedition.signal.fsamp));
            app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),idx_orig) = Pt;
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = setdiff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})},spikes1_noedges);
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = [app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}, spikes2 + idx_orig(1)-1];
            app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = sort(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})});

            oldsil = app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})};
            app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})} = getsil(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.signal.fsamp);
            app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})} = refinesil(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}, app.MUedition.signal.fsamp);

            app.EditField_2.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];

            if app.SILCheckBox.Value == 1
                bar(app.UIAxesSIL, app.MUedition.edition.time(app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,1)), app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                app.UIAxesSIL.XAxis.Visible = 'Off';
                app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412];
            end

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);

            % Color code based one the change in SIL value
            if (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) > 0.04
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.6350 0.0780 0.1840]);
            elseif (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) > 0.02 && (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) < 0.04
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.8500 0.3250 0.0980]);
            elseif (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) > 0 && (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) < 0.02
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.3010 0.7450 0.9330]);
            else
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.4660 0.6740 0.1880]);
            end
            hold(app.UIAxesSpiketrain, 'off')

            distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end

            if ~isempty(distime1)
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end

            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSIL.XLim = [app.graphstart app.graphend];
            app.UIAxesSIL.YLim = [0.5 1];

            disp(['... finished after ', num2str(round(toc(t),2)), 'sec'])

        end

        % Button pushed function: ExtendMUfilter
        function ExtendMUfilterPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};

            % Get the indexes of the current window
            idx = find(app.MUedition.edition.time > app.graphstart & app.MUedition.edition.time < app.graphend);
            step = floor(length(idx)/2);

            % Zoom out on both plots
            app.graphstart = app.MUedition.edition.time(1);
            app.graphend = app.MUedition.edition.time(end);
            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesSIL.XLim = [app.graphstart app.graphend];
            app.UIAxesSIL.YLim = [0.5 1];

            % Extend MU filters
            % Move forward
            idx1 = idx;
            for j = 1:ceil((length(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:))-idx(end))/step)
                idx1 = idx1+step;
                idx1(idx1>length(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:))) = [];
                [app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}] = extendfilter(app.MUedition.signal.data(app.MUedition.edition.arraynb==str2double(C{2}),:), app.MUedition.signal.EMGmask{str2double(C{2})}, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}, idx1, app.MUedition.signal.fsamp, app.MUedition.signal.emgtype(str2double(C{2})));
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                drawnow
            end
            
            % Move backward
            idx1 = idx;
            for j = 1:ceil(idx(1)/step)
                idx1 = idx1-step;
                idx1(idx1<1) = [];
                [app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}] = extendfilter(app.MUedition.signal.data(app.MUedition.edition.arraynb==str2double(C{2}),:), app.MUedition.signal.EMGmask{str2double(C{2})}, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}, idx1, app.MUedition.signal.fsamp, app.MUedition.signal.emgtype(str2double(C{2})));
                % Update graph
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                drawnow
            end

            oldsil = app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})};
            app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})} = getsil(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.signal.fsamp);
            app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})} = refinesil(app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}, app.MUedition.signal.fsamp);

            app.EditField_2.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];
            
            if app.SILCheckBox.Value == 1
                bar(app.UIAxesSIL, app.MUedition.edition.time(app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,1)), app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                app.UIAxesSIL.XAxis.Visible = 'Off';
                app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412];
            end

            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
            
            % Color code based one the change in SIL value
            if (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) > 0.04
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.6350 0.0780 0.1840]);
            elseif (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) > 0.02 && (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) < 0.04
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.8500 0.3250 0.0980]);
            elseif (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) > 0 && (oldsil - app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})}) < 0.02
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.3010 0.7450 0.9330]);
            else
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.4660 0.6740 0.1880]);
            end
            hold(app.UIAxesSpiketrain, 'off')

            distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end

            if ~isempty(distime1)
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end

            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSIL.XLim = [app.graphstart app.graphend];
            app.UIAxesSIL.YLim = [0.5 1];

        end

        % Button pushed function: RemovealltheoutliersButton
        function RemovealltheoutliersButtonPushed(app, event)
            fwb = waitbar(0, 'Starting batch processing');
            ite = 0;
            itetot = 0;
            for f = 1:app.MUedition.signal.ngrid
                itetot = itetot + size(app.MUedition.edition.Pulsetrain{f},1);
            end
            for f = 1:app.MUedition.signal.ngrid
                for mu = 1:size(app.MUedition.edition.Pulsetrain{f},1)
                    ite = ite + 1;
                    waitbar(ite/itetot, fwb,['Removing outliers for Array#' num2str(f) ' _MU#' num2str(mu)])
                    
                    distime = zeros(1, length(app.MUedition.edition.Dischargetimes{f,mu})-1);
                    for i = 1:length(app.MUedition.edition.Dischargetimes{f,mu})-1
                        distime(i) = (round((app.MUedition.edition.Dischargetimes{f,mu}(i+1) - app.MUedition.edition.Dischargetimes{f,mu}(i)) / 2) + app.MUedition.edition.Dischargetimes{f,mu}(i)) / app.MUedition.signal.fsamp;
                    end
                    DR = 1./((diff(app.MUedition.edition.Dischargetimes{f,mu}))/app.MUedition.signal.fsamp);

                    k = 1;
                    while (std(DR)/mean(DR)) > 0.3 && k < 30
                        k = k + 1;       
                        thres = mean(DR) + 3*std(DR);
                        idx = find(DR > thres);
                        if ~isempty(idx)
                            idxdel = zeros(1, length(idx));
                            for i = 1:length(idx)
                                if app.MUedition.edition.Pulsetrain{f}(mu,app.MUedition.edition.Dischargetimes{f,mu}(idx(i))) < app.MUedition.edition.Pulsetrain{f}(mu, app.MUedition.edition.Dischargetimes{f,mu}(idx(i)+1))
                                    idxdel(i) = idx(i);
                                else
                                    idxdel(i) = idx(i)+1;
                                end
                            end
                            app.MUedition.edition.Dischargetimes{f,mu}(idxdel) = [];
                        end

                        %update DR
                        clearvars distime DR idxdel
                        for i = 1:length(app.MUedition.edition.Dischargetimes{f,mu})-1
                            distime(i) = (round((app.MUedition.edition.Dischargetimes{f,mu}(i+1) - app.MUedition.edition.Dischargetimes{f,mu}(i)) / 2) + app.MUedition.edition.Dischargetimes{f,mu}(i)) / app.MUedition.signal.fsamp;
                        end
                        DR = 1./((diff(app.MUedition.edition.Dischargetimes{f,mu}))/app.MUedition.signal.fsamp);
                    end
                    clearvars distime DR
                end
            end
            delete(fwb)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');

            % Update graph
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
            plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);

            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.YLim = [0 max(DR1)*1.5];
        end

        % Button pushed function: UpdateallMUfiltersButton
        function UpdateallMUfiltersButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            
            % Update MU filter
            nbextchan = 1000;
            fwb = waitbar(0, 'Starting batch processing');
            ite = 0;
            itetot = 0;
            for f = 1:app.MUedition.signal.ngrid
                itetot = itetot + size(app.MUedition.edition.Pulsetrain{f},1);
            end
            for f = 1:app.MUedition.signal.ngrid
                EMG = app.MUedition.signal.data(app.MUedition.edition.arraynb==f,:);
                EMG = EMG(app.MUedition.signal.EMGmask{f}==0,:);
                exFactor1 = round(nbextchan/size(EMG,1));
                eSIG = extend(EMG,exFactor1);
                ReSIG = eSIG*eSIG'/length(eSIG);
                iReSIGt = pinv(ReSIG);

                for mu = 1:size(app.MUedition.edition.Pulsetrain{f},1)
                    ite = ite + 1;
                    waitbar(ite/itetot, fwb,['Recalculating filter for Array#' num2str(f) ' MU#' num2str(mu)])

                    if length(app.MUedition.edition.Dischargetimes{f,mu})>2
                        MUFilters = sum(eSIG(:,app.MUedition.edition.Dischargetimes{f,mu}),2);
                        Pt = (MUFilters'*iReSIGt)*eSIG; % Update the pulse train
                        Pt= Pt(1:size(EMG,2));
                        Pt = Pt .* abs(Pt); % Normalized and update the pulse train
                        [~,spikes] = findpeaks(Pt,'MinPeakDistance', round(app.MUedition.signal.fsamp*0.005)); % Peak detection
                        Pt = Pt/mean(maxk(Pt(spikes),10));
                        [L,C2] = kmeans(Pt(spikes)',2); % Kmean classification
                        [~, idx2] = max(C2); % Find the class with the highest centroid
                        spikes2 = spikes(L==idx2);
                        spikes2(Pt(spikes2)>mean(Pt(spikes2))+3*std(Pt(spikes2))) = []; % remove the outliers of the pulse train for the calculation of the filter
            
                        app.MUedition.edition.Pulsetrain{f}(mu,:) = Pt;
                        app.MUedition.edition.Dischargetimes{f,mu} = [];
                        app.MUedition.edition.Dischargetimes{f,mu} = spikes2;
                        app.MUedition.edition.silval{f,mu} = getsil(app.MUedition.edition.Pulsetrain{f}(mu,:), app.MUedition.signal.fsamp);
                        app.MUedition.edition.silvalcon{f,mu} = refinesil(app.MUedition.edition.Pulsetrain{f}(mu,:), app.MUedition.edition.Dischargetimes{f,mu}, app.MUedition.signal.fsamp);

                        clearvars Pt MUFilters spikest
                    end
                end
                clearvars EMG eSIG iReSIGt dischan ReSIG
            end
            delete(fwb)
            % Update the graph
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
            hold(app.UIAxesSpiketrain, 'on')
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
            plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
            hold(app.UIAxesSpiketrain, 'off')

            app.EditField_2.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];

            if app.SILCheckBox.Value == 1
                bar(app.UIAxesSIL, app.MUedition.edition.time(app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,1)), app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                app.UIAxesSIL.XAxis.Visible = 'Off';
                app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412];
            end

            distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
            for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
            end
            if ~isempty(distime1)
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end

            app.UIAxesSpiketrain.YLim = [-0.05 1.5];
            app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
            app.UIAxesDR.XLim = [app.graphstart app.graphend];
            app.UIAxesSIL.XLim = [app.graphstart app.graphend];
            app.UIAxesSIL.YLim = [0.5 1];

        end

        % Button pushed function: RemoveflaggedMUButton
        function RemoveflaggedMUButtonPushed(app, event)
            % Remove the flagged motor units
            fwb = waitbar(0, 'Checking flagged units');
            ite = 0;
            itetot = 0;
            for f = 1:size(app.MUedition.edition.Pulsetrain,2)
                itetot = itetot + size(app.MUedition.edition.Pulsetrain{f},1);
            end
            
            silvalcon_placeholder_idx   = find(~cellfun(@isempty, app.MUedition.edition.silvalcon), 1, 'first');
            silvalcon_placeholder       = app.MUedition.edition.silvalcon{silvalcon_placeholder_idx};

            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                app.MUedition.edition.Pulsetrainclean{i} = app.MUedition.edition.Pulsetrain{i};
                app.MUedition.edition.Distimeclean{i} = {};
                app.MUedition.edition.silvalclean{i}={}; % add
                app.MUedition.edition.silvalconclean{i}={}; % add
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUedition.edition.Distimeclean{i}{j} = app.MUedition.edition.Dischargetimes{i,j};
                    app.MUedition.edition.silvalclean{i}{j} = app.MUedition.edition.silval{i,j};
                    if isempty(app.MUedition.edition.silvalcon{i,j})
                        app.MUedition.edition.silvalconclean{i}{j} = silvalcon_placeholder;
                        disp(['please check MU #' num2str(j), ' in grid #', num2str(i)]);
                    else
                        app.MUedition.edition.silvalconclean{i}{j} = app.MUedition.edition.silvalcon{i,j};
                    end%if
                end
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    idx = size(app.MUedition.edition.Pulsetrain{i},1)+1-j;
                    if length(app.MUedition.edition.Dischargetimes{i,idx}) == 2 && mean(app.MUedition.edition.Pulsetrain{i}(idx,:)) == 0
                        app.MUedition.edition.Distimeclean{i}{idx} = [];
                        app.MUedition.edition.silvalclean{i}{idx} = [];
                        app.MUedition.edition.silvalconclean{i}{idx} = [];
                        app.MUedition.edition.Pulsetrainclean{i}(idx,:) = [];
                    end
                    ite = ite + 1;
                    waitbar(ite/itetot, fwb,['Checking flagged units for Array#' num2str(i) ' _MU#' num2str(j)])
                end
                app.MUedition.edition.Distimeclean{i} = app.MUedition.edition.Distimeclean{i}(~cellfun('isempty',app.MUedition.edition.Distimeclean{i}));
                app.MUedition.edition.silvalclean{i} = app.MUedition.edition.silvalclean{i}(~cellfun('isempty',app.MUedition.edition.silvalclean{i}));
                app.MUedition.edition.silvalconclean{i} = app.MUedition.edition.silvalconclean{i}(~cellfun('isempty',app.MUedition.edition.silvalconclean{i}));
            end

            app.MUedition.edition.Dischargetimes = {};
            app.MUedition.edition.silval = {};
            app.MUedition.edition.silvalcon = {};
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                app.MUedition.edition.Pulsetrain{i} = app.MUedition.edition.Pulsetrainclean{i};
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUedition.edition.Dischargetimes{i,j} = app.MUedition.edition.Distimeclean{i}{j};
                    app.MUedition.edition.silval{i,j} = app.MUedition.edition.silvalclean{i}{j};
                    app.MUedition.edition.silvalcon{i,j} = app.MUedition.edition.silvalconclean{i}{j};
                end
            end

            app.MUdisplayedDropDown.Items = {};
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                % Update the list and load the edited files
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Array_', num2str(i), '_MU_' , num2str(j)]});
                end
                try%paul
                    app.MUdisplayedDropDown.Value = app.MUdisplayedDropDown.Items{1};
                catch%paul
                    disp(['No MU left in that Array_', num2str(i)])%paul
                    
                end%paul
            end

            % app.MUedition.edition.Pulsetrain            = app.MUedition.edition.Pulsetrain(~cellfun('isempty',app.MUedition.edition.Pulsetrain))';%paul
            % app.MUedition.edition.Pulsetrainclean       = app.MUedition.edition.Pulsetrainclean(~cellfun('isempty',app.MUedition.edition.Pulsetrainclean))';%paul
            % app.MUedition.edition.Dischargetimes        = app.MUedition.edition.Dischargetimes(~cellfun('isempty',app.MUedition.edition.Dischargetimes))';%paul
            % app.MUedition.edition.silval                = app.MUedition.edition.silval(~cellfun('isempty',app.MUedition.edition.silval))';%paul
            % app.MUedition.edition.silvalcon             = app.MUedition.edition.silvalcon(~cellfun('isempty',app.MUedition.edition.silvalcon))';%paul
            % app.MUedition.edition.Distimeclean          = app.MUedition.edition.Distimeclean(~cellfun('isempty',app.MUedition.edition.Distimeclean))';%paul
            % app.MUedition.edition.silvalclean           = app.MUedition.edition.silvalclean(~cellfun('isempty',app.MUedition.edition.silvalclean))';%paul
            % app.MUedition.edition.silvalconclean        = app.MUedition.edition.silvalconclean(~cellfun('isempty',app.MUedition.edition.silvalconclean))';%paul


            if ~isempty(app.MUdisplayedDropDown.Items)
                C = strsplit(app.MUdisplayedDropDown.Value,'_');
                % Update the graph
                app.EditField_2.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];

                if app.SILCheckBox.Value == 1
                    bar(app.UIAxesSIL, app.MUedition.edition.time(app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,1)), app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                    yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                    app.UIAxesSIL.XAxis.Visible = 'Off';
                    app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412];
                end

                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            else
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, zeros(length(app.MUedition.edition.time),1), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                plot(app.UIAxesDR, app.MUedition.edition.time, zeros(length(app.MUedition.edition.time),1), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                app.MUdisplayedDropDown.Items = {'No MUs'};
                app.MUdisplayedDropDown.Value = 'No MUs';
                app.EditField_2.Value = 'no SIL ';
            end

            delete(fwb)
        end

        % Button pushed function: RemoveduplicateswithingridsButton
        function RemoveduplicateswithingridsButtonPushed(app, event)
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                app.MUedition.edition.Pulsetrainclean{i} = app.MUedition.edition.Pulsetrain{i};
                app.MUedition.edition.Distimeclean{i} = {};
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUedition.edition.Distimeclean{i}{j} = app.MUedition.edition.Dischargetimes{i,j};
                end
                if ~isempty(app.MUedition.edition.Pulsetrainclean{i})
                    [app.MUedition.edition.Pulsetrainclean{i}, app.MUedition.edition.Distimeclean{i}] = remduplicates(app.MUedition.edition.Pulsetrainclean{i}, app.MUedition.edition.Distimeclean{i}, app.MUedition.edition.Distimeclean{i}, round(app.MUedition.signal.fsamp/40), 0.00025, app.DuplicatethresholdEditField.Value, app.MUedition.signal.fsamp);
                end
            end

            app.MUedition.edition.Dischargetimes = {};
            app.MUedition.edition.silval = {};
            app.MUedition.edition.silvalcon = {};

            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                app.MUedition.edition.Pulsetrain{i} = app.MUedition.edition.Pulsetrainclean{i};
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUedition.edition.Dischargetimes{i,j} = app.MUedition.edition.Distimeclean{i}{j};
                    app.MUedition.edition.silval{i,j} = getsil(app.MUedition.edition.Pulsetrain{i}(j,:), app.MUedition.signal.fsamp);
                    app.MUedition.edition.silvalcon{i,j} = refinesil(app.MUedition.edition.Pulsetrain{i}(j,:), app.MUedition.edition.Dischargetimes{i,j}, app.MUedition.signal.fsamp);
                end
            end

            app.MUdisplayedDropDown.Items = {};
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                % Update the list and load the edited files
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Array_', num2str(i), '_MU_' , num2str(j)]});
                end
                try%paul
                    app.MUdisplayedDropDown.Value = app.MUdisplayedDropDown.Items{1};
                catch%paul
                    disp(['No MU in that Array_', num2str(i)])%paul
                    
                end%paul
            end


            if ~isempty(app.MUdisplayedDropDown.Items)
                C = strsplit(app.MUdisplayedDropDown.Value,'_');
                % Update the graph
                app.EditField_2.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];

                if app.SILCheckBox.Value == 1
                    bar(app.UIAxesSIL, app.MUedition.edition.time(app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,1)), app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                    yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                    app.UIAxesSIL.XAxis.Visible = 'Off';
                    app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412];
                end

                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            else
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, zeros(length(app.MUedition.edition.time),1), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                plot(app.UIAxesDR, app.MUedition.edition.time, zeros(length(app.MUedition.edition.time),1), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                app.MUdisplayedDropDown.Items = {'No MUs'};
                app.MUdisplayedDropDown.Value = 'No MUs';
                app.EditField_2.Value = 'no SIL ';
            end
        end

        % Button pushed function: RemoveduplicatesbetweengridsButton
        function RemoveduplicatesbetweengridsButtonPushed(app, event)
            nmu = 0;
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                if ~isempty(app.MUedition.edition.Pulsetrain{i})
                    nmu = nmu + size(app.MUedition.edition.Pulsetrain{i},1);
                end
            end
            
            PulseT = zeros(nmu, length(app.MUedition.signal.target));
            Distim = cell(1,nmu);
            muscle = zeros(1,nmu);
            mu = 1;
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                if ~isempty(app.MUedition.edition.Pulsetrain{i})
                    for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                        PulseT(mu,:) = app.MUedition.edition.Pulsetrain{i}(j,:);
                        Distim{mu} = app.MUedition.edition.Dischargetimes{i,j};
                        muscle(mu) = i;
                        mu = mu+ 1;
                    end
                end
            end
            
            [PulseT, Distim, muscle] = remduplicatesbgrids(PulseT, Distim, muscle, round(app.MUedition.signal.fsamp/40), 0.00025, app.DuplicatethresholdEditField.Value, app.MUedition.signal.fsamp);
            app.MUedition.edition.Dischargetimes = {};
            app.MUedition.edition.silval = {};
            app.MUedition.edition.silvalcon = {};

            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                idx = find(muscle == i);
                app.MUedition.edition.Pulsetrain{i} = PulseT(idx,:);
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUedition.edition.Dischargetimes{i,j} = Distim{idx(j)};
                    app.MUedition.edition.silval{i,j} = getsil(app.MUedition.edition.Pulsetrain{i}(j,:), app.MUedition.signal.fsamp);
                    app.MUedition.edition.silvalcon{i,j} = refinesil(app.MUedition.edition.Pulsetrain{i}(j,:), app.MUedition.edition.Dischargetimes{i,j}, app.MUedition.signal.fsamp);
                end
            end

            app.MUdisplayedDropDown.Items = {};
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                % Update the list and load the edited files
                if size(app.MUedition.edition.Pulsetrain{i},1)>0 %add paul
                    for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                        app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Array_', num2str(i), '_MU_' , num2str(j)]});
                    end
                    app.MUdisplayedDropDown.Value = app.MUdisplayedDropDown.Items{1};
                end%for
            end


            if ~isempty(app.MUdisplayedDropDown.Items)
                C = strsplit(app.MUdisplayedDropDown.Value,'_');
                % Update the graph
                app.EditField_2.Value = ['SIL = ' num2str(app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})})];

                if app.SILCheckBox.Value == 1
                    bar(app.UIAxesSIL, app.MUedition.edition.time(app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,1)), app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})}(:,2), 'EdgeColor', [0.9412 0.9412 0.9412], 'FaceColor', [0.15 0.15 0.15]);
                    yline(app.UIAxesSIL, 0.9, 'LineWidth', 2, 'Color', [0.4660 0.6740 0.1880]);
                    app.UIAxesSIL.XAxis.Visible = 'Off';
                    app.UIAxesSIL.YColor = [0.9412 0.9412 0.9412];
                end

                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1, length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            else
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, zeros(length(app.MUedition.edition.time),1), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                plot(app.UIAxesDR, app.MUedition.edition.time, zeros(length(app.MUedition.edition.time),1), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                app.MUdisplayedDropDown.Items = {'No MUs'};
                app.MUdisplayedDropDown.Value = 'No MUs';
                app.EditField_2.Value = 'no SIL ';
            end
        end

        % Button pushed function: PlotMUspiketrainsButton
        function PlotMUspiketrainsButtonPushed(app, event)
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                firings = nan(size(app.MUedition.edition.Pulsetrain{i}));
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    firings(j,app.MUedition.edition.Dischargetimes{i,j}) = j;
                end
                subplot(1,app.MUedition.signal.ngrid,i)
                plot(app.MUedition.edition.time,firings,'|','MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412])
                hold on
                plot(app.MUedition.edition.time,app.MUedition.signal.target/max(app.MUedition.signal.target)*j,'--','LineWidth',1,'Color',[0.85 0.33 0.10]);
                title(['Array#' num2str(i) ' with ' num2str(j) ' MUs'], 'Color', [0.9412 0.9412 0.9412], 'FontName', 'Avenir Next')
                xlabel('Time (s)', 'FontName', 'Avenir Next')
                ylabel('MU#', 'FontName', 'Avenir Next')
                ylim([0 j+1])
                set(gcf,'Color', [0.15 0.15 0.15]);
                set(gcf,'units','normalized','outerposition',[0 0 1 1])
                set(gca,'Color', [0.15 0.15 0.15], 'XColor', [0.9412 0.9412 0.9412], 'YColor', [0.9412 0.9412 0.9412]);
            end
            sgtitle(['Raster plots for ' num2str(i) ' arrays'], 'FontName', 'Avenir Next', 'FontSize', 25, 'Color', [0.9412 0.9412 0.9412])
        end

        % Button pushed function: PlotMUfiringratesButton
        function PlotMUfiringratesButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            win = hann(app.MUedition.signal.fsamp);
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                firings = zeros(size(app.MUedition.edition.Pulsetrain{i}));
                smoothdr = zeros(size(app.MUedition.edition.Pulsetrain{i}));
                subplot(1,app.MUedition.signal.ngrid,i)
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    firings(j,app.MUedition.edition.Dischargetimes{i,j}) = 1;
                    smoothdr(j,:) = conv(firings(j,:),win,'same');
                    if i == str2double(C{2}) && j == str2double(C{4})
                        plot(app.MUedition.edition.time,smoothdr(j,:), 'Color', [0.85 0.33 0.10], 'LineWidth', 3)
                        hold on
                    else
                        plot(app.MUedition.edition.time,smoothdr(j,:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1)
                        hold on
                    end
                end
                clearvars firings smoothdr

                title(['Array#' num2str(i) ' with ' num2str(j) ' MUs'], 'Color', [0.9412 0.9412 0.9412], 'FontName', 'Avenir Next')
                xlabel('Time (s)', 'FontName', 'Avenir Next')
                ylabel('Smoothed discharge rates', 'FontName', 'Avenir Next')
                set(gcf,'Color', [0.15 0.15 0.15]);
                set(gcf,'units','normalized','outerposition',[0 0 1 1])
                set(gca,'Color', [0.15 0.15 0.15], 'XColor', [0.9412 0.9412 0.9412], 'YColor', [0.9412 0.9412 0.9412]);
            end
            sgtitle(['Smoothed discharge rates for ' num2str(i) ' arrays'], 'FontName', 'Avenir Next', 'FontSize', 25, 'Color', [0.9412 0.9412 0.9412])
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fwb = waitbar(0, 'Checking flagged units');
            ite = 0;
            itetot = 0;
            for f = 1:size(app.MUedition.edition.Pulsetrain,2)
                itetot = itetot + size(app.MUedition.edition.Pulsetrain{f},1);
            end

            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                app.MUedition.edition.Pulsetrainclean{i} = app.MUedition.edition.Pulsetrain{i};
                app.MUedition.edition.Distimeclean{i} = {};
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUedition.edition.Distimeclean{i}{j} = app.MUedition.edition.Dischargetimes{i,j};
                end
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    idx = size(app.MUedition.edition.Pulsetrain{i},1)+1-j;
                    if length(app.MUedition.edition.Dischargetimes{i,idx}) == 2 && mean(app.MUedition.edition.Pulsetrain{i}(idx,:)) == 0
                        app.MUedition.edition.Distimeclean{i}{idx} = [];
                        app.MUedition.edition.Pulsetrainclean{i}(idx,:) = [];
                    end
                    ite = ite + 1;
                    waitbar(ite/itetot, fwb,['Checking flagged units for Array#' num2str(i) ' _MU#' num2str(j)])
                end
                app.MUedition.edition.Distimeclean{i} = app.MUedition.edition.Distimeclean{i}(~cellfun('isempty',app.MUedition.edition.Distimeclean{i}));
            end

            app.MUedition.edition.Dischargetimes = {};
            for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                app.MUedition.edition.Pulsetrain{i} = app.MUedition.edition.Pulsetrainclean{i};
                for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                    app.MUedition.edition.Dischargetimes{i,j} = app.MUedition.edition.Distimeclean{i}{j};
                end
            end
            
            delete(fwb)

            if contains(app.filename2,'edited')
                savename = [app.pathname2 app.filename2];
            else
                savename = [app.pathname2 app.filename2 '_edited.mat'];
            end
            signal = app.MUedition.signal;
            parameters = app.MUedition.parameters;
            edition = app.MUedition.edition;
            flagged = app.Flagged;
            % peeloff_spikes = app.PeeloffSpikes;
            % save(savename, 'signal', 'parameters', 'edition', 'flagged', 'peeloff_spikes', '-v7.3');
            save(savename, 'signal', 'parameters', 'edition', 'flagged', '-v7.3');
            disp('file saved')
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case 'leftarrow'
                    ScrollleftButtonPushed(app);
                case 'rightarrow'
                    ScrollrightButtonPushed(app);
                case 'uparrow'
                    ZoominButtonPushed(app);
                case 'downarrow'
                    ZoomoutButtonPushed(app);
                case 'a'
                    AddspikesButtonPushed(app);
                case 'd'
                    DeletespikesButtonPushed(app);
                case 'r'
                    RemoveoutliersButtonPushed(app);
                case 'space'
                    UpdateMUfilterButtonPushed(app);
                case 's'
                    LockspikesButtonPushed(app);
                case 'e'
                    ExtendMUfilterPushed(app);
                case 'w'
                    DeleteDRButtonPushed(app);
                case 'z'
                    UndoButtonPushed(app);
                case 't'
                    plotSTAButtonPushed(app);
                case 'g'
                    closefiguresButtonPushed(app);
                case 'p'
                    app.UsepeeledoffsignalCheckBox.Value = ~app.UsepeeledoffsignalCheckBox.Value;
                    UsepeeledoffsignalCheckBoxValueChanged(app);
                case 'i'
                    app.IgnorewindowsCheckBox.Value = ~app.IgnorewindowsCheckBox.Value;
                    IgnorewindowsCheckBoxValueChanged(app);
                    
            end

        end

        % Value changed function: FlagMUforDropDown
        function FlagMUforDropDownValueChanged(app, event)
            flagselection = app.FlagMUforDropDown.Value;
            

            % if ~isfield(app.Flagged,'editAfterPeeloff')
            %     app.Flagged.editAfterPeeloff.Pulsetrain     = cell(1,numel(app.MUedition.edition.Pulsetrain));
            %     app.Flagged.editAfterPeeloff.Dischargetimes = cell(1,numel(app.MUedition.edition.Pulsetrain));
            % end%if
            if ~isfield(app.Flagged,'peeloffOnly')
                app.Flagged.peeloffOnly.Pulsetrain          = cell(1,numel(app.MUedition.edition.Pulsetrain));
                app.Flagged.peeloffOnly.Dischargetimes      = cell(1,numel(app.MUedition.edition.Pulsetrain));
            end%if
            
            Cunsplit = app.MUdisplayedDropDown.Value;
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            app.Backup.Pulsetrain = app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :);
            app.Backup.Dischargetimes = app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})};
            if ismember(flagselection, {'deletion','edit after peeloff', 'peeloff only'})
                app.FlagMUforDropDown.Enable = "off";

                app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}), :) = 0;
                app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})} = [1 app.MUedition.signal.fsamp];
    
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),:), 'Color', [0.9412 0.9412 0.9412], 'LineWidth', 1);
                hold(app.UIAxesSpiketrain, 'on')
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time, app.MUedition.signal.target/max(app.MUedition.signal.target), '--', 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxesSpiketrain, app.MUedition.edition.time(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), app.MUedition.edition.Pulsetrain{str2double(C{2})}(str2double(C{4}),app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}), 'o', 'LineWidth', 1, 'MarkerSize', 10, 'Color', [0.85 0.33 0.10]);
                hold(app.UIAxesSpiketrain, 'off')
    
                distime1 = zeros(1,length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1);
                for i = 1:length(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})})-1
                    distime1(i) = (round((app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i+1) - app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / 2) + app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}(i)) / app.MUedition.signal.fsamp;
                end
                DR1 = 1./((diff(app.MUedition.edition.Dischargetimes{str2double(C{2}),str2double(C{4})}))/app.MUedition.signal.fsamp);
    
                plot(app.UIAxesDR, distime1, DR1, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
    
                app.UIAxesSpiketrain.XLim = [app.graphstart app.graphend];
                app.UIAxesDR.XLim = [app.graphstart app.graphend];
                app.UIAxesSpiketrain.YLim = [-0.05 1.5];
                app.UIAxesDR.YLim = [0 max(DR1)*1.5];
            end%if

            switch flagselection
                case 'edit after peeloff'
                    nextidx = find(cellfun(@(mu) ~isempty(mu), app.MUedition.edition.Dischargetimes(str2double(C{2}),:)), 1, 'last')+1;
                    app.MUedition.edition.Dischargetimes{str2double(C{2}),nextidx}  = app.Backup.Dischargetimes;
                    app.MUedition.edition.Pulsetrain{str2double(C{2})}(nextidx,:)   = app.Backup.Pulsetrain;
                    app.MUedition.edition.silval{str2double(C{2}),nextidx}          = app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})};
                    app.MUedition.edition.silvalcon{str2double(C{2}),nextidx}       = app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})};

                    %%update the list
                    app.MUdisplayedDropDown.Items = {};
                    for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                        % Update the list and load the edited files
                        for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                            app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Array_', num2str(i), '_MU_' , num2str(j)]});
                        end
                    end
                    
                    app.MUdisplayedDropDown.Value = app.MUdisplayedDropDown.Items{find(ismember(app.MUdisplayedDropDown.Items,Cunsplit))};
                    

                    % nextidx = numel(app.Flagged.editAfterPeeloff.Pulsetrain{str2double(C{2})})+1;
                    % app.Flagged.editAfterPeeloff.Pulsetrain{str2double(C{2})}{nextidx}       = app.Backup.Pulsetrain;
                    % app.Flagged.editAfterPeeloff.Dischargetimes{str2double(C{2})}{nextidx}   = app.Backup.Dischargetimes;
                case 'clone'
                    nextidx = find(cellfun(@(mu) ~isempty(mu), app.MUedition.edition.Dischargetimes(str2double(C{2}),:)), 1, 'last')+1;
                    app.MUedition.edition.Dischargetimes{str2double(C{2}),nextidx}  = app.Backup.Dischargetimes;
                    app.MUedition.edition.Pulsetrain{str2double(C{2})}(nextidx,:)   = app.Backup.Pulsetrain;
                    app.MUedition.edition.silval{str2double(C{2}),nextidx}          = app.MUedition.edition.silval{str2double(C{2}),str2double(C{4})};
                    app.MUedition.edition.silvalcon{str2double(C{2}),nextidx}       = app.MUedition.edition.silvalcon{str2double(C{2}),str2double(C{4})};

                    %%update the list
                    app.MUdisplayedDropDown.Items = {};
                    for i = 1:size(app.MUedition.edition.Pulsetrain,2)
                        % Update the list and load the edited files
                        for j = 1:size(app.MUedition.edition.Pulsetrain{i},1)
                            app.MUdisplayedDropDown.Items = horzcat(app.MUdisplayedDropDown.Items, {['Array_', num2str(i), '_MU_' , num2str(j)]});
                        end
                    end
                    
                    app.MUdisplayedDropDown.Value = app.MUdisplayedDropDown.Items{find(ismember(app.MUdisplayedDropDown.Items,Cunsplit))};
                    

                    % nextidx = numel(app.Flagged.editAfterPeeloff.Pulsetrain{str2double(C{2})})+1;
                    % app.Flagged.editAfterPeeloff.Pulsetrain{str2double(C{2})}{nextidx}       = app.Backup.Pulsetrain;
                    % app.Flagged.editAfterPeeloff.Dischargetimes{str2double(C{2})}{nextidx}   = app.Backup.Dischargetimes;
                case 'peeloff only'
                    nextidx = numel(app.Flagged.peeloffOnly.Dischargetimes{str2double(C{2})})+1;
                    app.Flagged.peeloffOnly.Pulsetrain{str2double(C{2})}(nextidx,:)         = app.Backup.Pulsetrain;
                    app.Flagged.peeloffOnly.Dischargetimes{str2double(C{2})}{nextidx}       = app.Backup.Dischargetimes;
                    [app.Flagged.peeloffOnly.Pulsetrain{str2double(C{2})}, app.Flagged.peeloffOnly.Dischargetimes{str2double(C{2})}] = remduplicates(app.Flagged.peeloffOnly.Pulsetrain{str2double(C{2})}, app.Flagged.peeloffOnly.Dischargetimes{str2double(C{2})}, app.Flagged.peeloffOnly.Dischargetimes{str2double(C{2})}, round(app.MUedition.signal.fsamp/40), 0.00025, app.DuplicatethresholdEditField.Value, app.MUedition.signal.fsamp);
            end%switch


        end

        % Value changed function: UsepeeledoffsignalCheckBox
        function UsepeeledoffsignalCheckBoxValueChanged(app, event)
            usepeeloff = app.UsepeeledoffsignalCheckBox.Value;

            app.PeeloffMUofcurrentgridSwitch.Enable             = usepeeloff;
            app.PeeloffMUofcurrentgridSwitchLabel.Enable        = usepeeloff;
            app.PlotpeeloffsourcesButton.Enable                 = usepeeloff;
            app.seperatenumbersbycommaEditField.Enable        = usepeeloff;
            app.seperatenumbersbycommaLabel.Enable   = usepeeloff;

            if ~isfield(app.Flagged,'peeloffOnly')
                app.Flagged.peeloffOnly.Pulsetrain          = cell(1,numel(app.MUedition.edition.Pulsetrain));
                app.Flagged.peeloffOnly.Dischargetimes      = cell(1,numel(app.MUedition.edition.Pulsetrain));
            end%if

            
        end

        % Button pushed function: PlotpeeloffsourcesButton
        function PlotpeeloffsourcesButtonPushed(app, event)
            C = strsplit(app.MUdisplayedDropDown.Value,'_');
            grd = str2double(C{2});
            idx = find(app.MUedition.edition.time > app.graphstart & app.MUedition.edition.time < app.graphend);

            thisMU      = str2double(C{4});
            poff_mu     = str2num(app.seperatenumbersbycommaEditField.Value);
            
            figure;
            hold on
            ylabs = cell(0);
            for mup = 1:find(cellfun(@(mu) ~isempty(mu), app.MUedition.edition.Dischargetimes(str2double(C{2}),:)), 1, 'last')
                distime = app.MUedition.edition.Dischargetimes{grd,mup};
                diszone = distime(distime>idx(1) & distime<idx(end));
                if mup == thisMU
                    plot(diszone,repmat(mup,1,length(diszone)), '|', 'MarkerSize', 15, 'Color', 'g')
                    ylabs{mup} = 'current MU';
                elseif length(distime) == 2 && all(distime ==[1 app.MUedition.signal.fsamp])
                    ylabs{mup} = 'flagged';
                elseif isempty(distime)
                    ylabs{mup} = 'no distimes';
                else
                    plot(diszone,repmat(mup,1,length(diszone)), '|', 'MarkerSize', 15, 'Color', 'b')
                    ylabs{mup} = num2str(mup);
                end%if
                if ismember(mup,poff_mu)
                    ylabs{mup} = ['selected ', ylabs{mup}];
                end%if
            end%for-->mup
            yline(mup+0.5, 'Color','k', 'LineWidth',1,'LineStyle','--');
            
            for mupo = 1:numel(app.Flagged.peeloffOnly.Dischargetimes{grd})
                mup = mup+1;
                distime = app.Flagged.peeloffOnly.Dischargetimes{grd}{mupo};
                diszone = distime(distime>idx(1) & distime<idx(end));
                
                plot(diszone,repmat(mup,1,length(diszone)), '|', 'MarkerSize', 15, 'Color', 'r')
                ylabs{mup} = num2str(mupo+1000);
                
            end%for-->mupo

            xlim([idx(1),idx(end)])
            
            ylim([0.5,mup+0.5])
            yticks(1:1:mup)
            yticklabels(ylabs)
            set(gca, 'YGrid', 'on')
            ylabel('Peeloff MU number')
            title({'avaliable peeloff sources within selected grid and time', 'above dashed line are the peeloff only discharge times'})


        end

        % Button pushed function: plotSTAButton
        function plotSTAButtonPushed(app, event)
            sta_MUedit(app);
        end

        % Button pushed function: closefiguresButton
        function closefiguresButtonPushed(app, event)
            close all
        end

        % Value changed function: IgnorewindowsCheckBox
        function IgnorewindowsCheckBoxValueChanged(app, event)
            useignore = app.IgnorewindowsCheckBox.Value;
            
            app.IgnoreselectedwindowsduringfilterupdateLabel.Enable         = useignore;
            app.IgnorewindowsduringupdateSwitch.Enable        = useignore;
            app.VisualizewindowDropDown.Enable                              = useignore;
            app.VisualizewindowDropDownLabel.Enable                         = useignore;
            app.seperatenumbersbycommaEditField_2.Enable                    = useignore;
            app.seperatenumbersbycommaEditField_2Label.Enable               = useignore;
            app.AddwindowButton.Enable                                      = useignore;

            if ~isfield(app.Flagged,'ignoreWindows')
                app.Flagged.ignoreWindows = cell(0);
            end%if
            
        end

        % Button pushed function: AddwindowButton
        function AddwindowButtonPushed(app, event)
        % Local shared state
        clickCount = 0;
        xClicks    = nan(1,2);
        permLines  = gobjects(1,2);
    
        ax  = app.UIAxesSpiketrain;
        yl  = ax.YLim;
        fig = app.UIFigure;
    
        % Moving guide line
        tempLine = line(ax, [NaN NaN], yl, 'Color', [0.5 0.5 0.5], 'LineStyle', '--', 'LineWidth', 1.5);
    
        % Enable callbacks
        fig.WindowButtonMotionFcn = @trackCursor;
        fig.WindowButtonDownFcn   = @mouseClick;
    
        % ---------------------------------------------------------
        % Nested functions
        % ---------------------------------------------------------
    
        function trackCursor(~,~)
            cp = ax.CurrentPoint;
            x  = cp(1,1);
            tempLine.XData = [x x];
        end
    
        function mouseClick(~,~)
            cp = ax.CurrentPoint;
            x  = cp(1,1);
    
            clickCount = clickCount + 1;
    
            % Draw permanent red line
            permLines(clickCount) = line(ax,[x x], yl,'Color', 'r','LineWidth', 2);
    
            % Store click
            xClicks(clickCount) = x;
    
            % Second click → finalize
            if clickCount == 2
                finalizeWindow();
            end
        end
    
        function xwindow = finalizeWindow()
    
            % Stop interaction
            fig.WindowButtonMotionFcn = [];
            fig.WindowButtonDownFcn   = [];
    
            delete(tempLine);
    
            % Sorted window
            xwindow = sort(xClicks);
    
            % Ask user
            choice = uiconfirm(fig, ...
                sprintf('Use this as an ignore window [%.3f  %.3f] ?', xwindow), ...
                'Confirm Window', ...
                'Options', {'Keep','Discard'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2);
    
            if strcmp(choice, 'Keep')
                storeWindow(xwindow);
            else
                delete(permLines);
            end
        end
    
        function storeWindow(xwindow)
    
            % Find first empty cell
            iw = app.Flagged.ignoreWindows;
    
            if isempty(iw)
                iw{1} = xwindow;
            else
                idx = find(cellfun(@isempty, iw), 1, 'first');
                if isempty(idx)
                    iw{end+1} = xwindow;
                else
                    iw{idx} = xwindow;
                end
            end
    
            app.Flagged.ignoreWindows = iw;
            % app.VisualizewindowDropDown.Items = [app.VisualizewindowDropDown.Items, num2str(numel(iw))];
        end

        end

        % Value changed function: VisualizewindowDropDown
        function VisualizewindowDropDownValueChanged(app, event)
            value = app.VisualizewindowDropDown.Value;

            if ~isfield(app.figHandles, 'visualizeWindows')
                app.figHandles.visualizeWindows = cell(0);
            end%if

            switch value
                case 'none'
                    iswindownumber = false;
                    if ~isempty(app.figHandles.visualizeWindows)
                        delete([app.figHandles.visualizeWindows{:}]);
                        app.figHandles.visualizeWindows = {};
                    end
                case 'all'
                    iswindownumber = false;
                    delete([app.figHandles.visualizeWindows{:}]);
                    app.figHandles.visualizeWindows = {};
                    nr_wds = numel(app.Flagged.ignoreWindows);
                    wds_color = cool(nr_wds);
                    for wd = 1:nr_wds
                        windowcoordinates = app.Flagged.ignoreWindows{wd};
                        app.figHandles.visualizeWindows{end+1} = xline(app.UIAxesSpiketrain,windowcoordinates,'-', {[num2str(wd),' start'], [num2str(wd),' end']}, 'Color', wds_color(wd,:));
                    end%for-->wd
                otherwise
                    iswindownumber = true;
            end%switch

            if iswindownumber
                windowcoordinates = app.Flagged.ignoreWindows{str2num(value)};
                app.figHandles.visualizeWindows{end+1} = xline(app.UIAxesSpiketrain,windowcoordinates,'r-', {[value,' start'], [value,' end']});
            end%if
        end

        % Drop down opening function: VisualizewindowDropDown
        function VisualizewindowDropDownOpening(app, event)
            iw      = app.Flagged.ignoreWindows;
            nr_iw   = numel(iw);

            if nr_iw == 0
                app.VisualizewindowDropDown.Items = {'none'};
            else
                app.VisualizewindowDropDown.Items = [{'none','all'}, ...
                                     arrayfun(@num2str,1:nr_iw,'UniformOutput',false)];
            end%if


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.tabs = uidropdown(app.UIFigure);
            app.tabs.Items = {'DECOMPOSITION', 'MANUAL EDITING'};
            app.tabs.ValueChangedFcn = createCallbackFcn(app, @tabsValueChanged, true);
            app.tabs.Value = 'MANUAL EDITING';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes_Decomp_1 = uiaxes(app.Panel_2);
            xlabel(app.UIAxes_Decomp_1, 'Time (s)')
            ylabel(app.UIAxes_Decomp_1, 'Pulse train')
            zlabel(app.UIAxes_Decomp_1, 'Z')
            app.UIAxes_Decomp_2 = uiaxes(app.Panel_2);
            xlabel(app.UIAxes_Decomp_2, 'Time (s)')
            ylabel(app.UIAxes_Decomp_2, 'Reference')
            zlabel(app.UIAxes_Decomp_2, 'Z')
            app.UIAxes_Decomp_2.ButtonDownFcn = createCallbackFcn(app, @AddspikesButtonPushed, true);
            app.EditField = uieditfield(app.Panel_2, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SILthresholdEditFieldLabel = uilabel(app.Panel);
            app.SILthresholdEditFieldLabel.Text = 'SIL threshold';
            app.SILthresholdEditField = uieditfield(app.Panel, 'numeric');
            app.SILthresholdEditField.Value = 0.9;
            app.NumberofiterationsEditField_2Label = uilabel(app.Panel);
            app.NumberofiterationsEditField_2Label.Text = 'Number of iterations';
            app.NumberofiterationsEditField_2 = uieditfield(app.Panel, 'numeric');
            app.NumberofiterationsEditField_2.Value = 150;
            app.EditField_saving_3 = uieditfield(app.Panel, 'text');
            app.EditField_saving_3.Editable = 'off';
            app.EditField_saving_3.Value = 'File name';
            app.SelectfileButton = uibutton(app.Panel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.NbofextendedchannelsLabel = uilabel(app.Panel);
            app.NbofextendedchannelsLabel.Text = 'Nb of extended channels ';
            app.NbofextendedchannelsEditField = uieditfield(app.Panel, 'numeric');
            app.NbofextendedchannelsEditField.Value = 1000;
            app.ReferenceDropDownLabel = uilabel(app.Panel);
            app.ReferenceDropDownLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.Panel);
            app.ReferenceDropDown.Items = {'Target', 'EMG amplitude'};
            app.ReferenceDropDown.Value = 'Target';
            app.CheckEMGDropDownLabel = uilabel(app.Panel);
            app.CheckEMGDropDownLabel.Text = 'Check EMG';
            app.CheckEMGDropDown = uidropdown(app.Panel);
            app.CheckEMGDropDown.Items = {'Yes', 'No'};
            app.CheckEMGDropDown.Value = 'Yes';
            app.PeeloffDropDownLabel = uilabel(app.Panel);
            app.PeeloffDropDownLabel.Text = 'Peeloff';
            app.PeeloffDropDown = uidropdown(app.Panel);
            app.PeeloffDropDown.Items = {'Yes', 'No'};
            app.PeeloffDropDown.Value = 'Yes';
            app.NumberofwindowsEditFieldLabel = uilabel(app.Panel);
            app.NumberofwindowsEditFieldLabel.Text = 'Number of windows';
            app.NumberofwindowsEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofwindowsEditField.Value = 1;
            app.CoVfilterDropDownLabel = uilabel(app.Panel);
            app.CoVfilterDropDownLabel.Text = 'CoV filter';
            app.CoVfilterDropDown = uidropdown(app.Panel);
            app.CoVfilterDropDown.Items = {'Yes', 'No'};
            app.CoVfilterDropDown.Value = 'Yes';
            app.InitialisationDropDownLabel = uilabel(app.Panel);
            app.InitialisationDropDownLabel.Text = 'Initialisation';
            app.InitialisationDropDown = uidropdown(app.Panel);
            app.InitialisationDropDown.Items = {'EMG max', 'Random'};
            app.InitialisationDropDown.Value = 'EMG max';
            app.RefineMUsDropDownLabel = uilabel(app.Panel);
            app.RefineMUsDropDownLabel.Text = 'Refine MUs';
            app.RefineMUsDropDown = uidropdown(app.Panel);
            app.RefineMUsDropDown.Items = {'Yes', 'No'};
            app.RefineMUsDropDown.Value = 'Yes';
            app.ThresholdtargetEditFieldLabel = uilabel(app.Panel);
            app.ThresholdtargetEditFieldLabel.Text = 'Threshold target';
            app.ThresholdtargetEditField = uieditfield(app.Panel, 'numeric');
            app.ThresholdtargetEditField.Value = 0.9;
            app.COVthresholdEditFieldLabel = uilabel(app.Panel);
            app.COVthresholdEditFieldLabel.Text = 'COV threshold';
            app.COVthresholdEditField = uieditfield(app.Panel, 'numeric');
            app.COVthresholdEditField.Value = 0.5;
            app.DuplicatethresholdEditFieldLabel = uilabel(app.Panel);
            app.DuplicatethresholdEditFieldLabel.Text = 'Duplicate threshold';
            app.DuplicatethresholdEditField = uieditfield(app.Panel, 'numeric');
            app.DuplicatethresholdEditField.Value = 0.3;
            app.ContrastfunctionLabel = uilabel(app.Panel);
            app.ContrastfunctionLabel.Text = 'Contrast function';
            app.ContrastfunctionDropDown = uidropdown(app.Panel);
            app.ContrastfunctionDropDown.Items = {'skew', 'kurtosis', 'logcosh'};
            app.ContrastfunctionDropDown.Value = 'skew';
            app.SetconfigurationButton = uibutton(app.Panel, 'push');
            app.SetconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SetconfigurationButtonPushed, true);
            app.SetconfigurationButton.Text = 'Set configuration';
            app.SegmentsessionButton = uibutton(app.Panel, 'push');
            app.SegmentsessionButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentsessionButtonPushed, true);
            app.SegmentsessionButton.Text = 'Segment session';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UIAxesSpiketrain = uiaxes(app.Panel_3);
            xlabel(app.UIAxesSpiketrain, 'Time (s)')
            ylabel(app.UIAxesSpiketrain, 'Pulse train (au)')
            zlabel(app.UIAxesSpiketrain, 'Z')
            app.UIAxesDR = uiaxes(app.Panel_3);
            xlabel(app.UIAxesDR, 'Time (s)')
            ylabel(app.UIAxesDR, 'Discharge rate (pps)')
            zlabel(app.UIAxesDR, 'Z')
            app.UIAxesSIL = uiaxes(app.Panel_3);
            xlabel(app.UIAxesSIL, 'Time (s)')
            ylabel(app.UIAxesSIL, 'SIL')
            zlabel(app.UIAxesSIL, 'Z')
            app.MUdisplayedDropDown = uidropdown(app.Panel_3);
            app.MUdisplayedDropDown.Items = {'No MUs'};
            app.MUdisplayedDropDown.ValueChangedFcn = createCallbackFcn(app, @MUdisplayedDropDownValueChanged, true);
            app.MUdisplayedDropDown.Enable = 'off';
            app.MUdisplayedDropDown.Value = 'No MUs';
            app.MUdisplayedDropDownLabel = uilabel(app.Panel_3);
            app.MUdisplayedDropDownLabel.Text = 'MU displayed #';
            app.RemoveoutliersButton = uibutton(app.Panel_3, 'push');
            app.RemoveoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveoutliersButtonPushed, true);
            app.RemoveoutliersButton.Text = 'Remove outliers';
            app.AddspikesButton = uibutton(app.Panel_3, 'push');
            app.AddspikesButton.ButtonPushedFcn = createCallbackFcn(app, @AddspikesButtonPushed, true);
            app.AddspikesButton.Text = 'Add spikes';
            app.DeletespikesButton = uibutton(app.Panel_3, 'push');
            app.DeletespikesButton.ButtonPushedFcn = createCallbackFcn(app, @DeletespikesButtonPushed, true);
            app.DeletespikesButton.Text = 'Delete spikes';
            app.DeleteDRButton = uibutton(app.Panel_3, 'push');
            app.DeleteDRButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteDRButtonPushed, true);
            app.DeleteDRButton.Text = 'Delete DR';
            app.ScrollleftButton = uibutton(app.Panel_3, 'push');
            app.ScrollleftButton.ButtonPushedFcn = createCallbackFcn(app, @ScrollleftButtonPushed, true);
            app.ScrollleftButton.Text = '< Scroll left';
            app.ZoominButton = uibutton(app.Panel_3, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ZoomoutButton = uibutton(app.Panel_3, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.ScrollrightButton = uibutton(app.Panel_3, 'push');
            app.ScrollrightButton.ButtonPushedFcn = createCallbackFcn(app, @ScrollrightButtonPushed, true);
            app.ScrollrightButton.Text = 'Scroll right >';
            app.UpdateMUfilterButton = uibutton(app.Panel_3, 'push');
            app.UpdateMUfilterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMUfilterButtonPushed, true);
            app.UpdateMUfilterButton.Text = 'Update MU filter';
            app.ExtendMUfilter = uibutton(app.Panel_3, 'push');
            app.ExtendMUfilter.ButtonPushedFcn = createCallbackFcn(app, @ExtendMUfilterPushed, true);
            app.ExtendMUfilter.Text = 'Extend MU filter';
            app.EditField_2 = uieditfield(app.Panel_3, 'text');
            app.UndoButton = uibutton(app.Panel_3, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.LockspikesButton = uibutton(app.Panel_3, 'push');
            app.LockspikesButton.ButtonPushedFcn = createCallbackFcn(app, @LockspikesButtonPushed, true);
            app.LockspikesButton.Text = 'Lock spikes';
            app.FlagMUforDropDown = uidropdown(app.Panel_3);
            app.FlagMUforDropDown.Items = {'keep', 'deletion', 'edit after peeloff', 'peeloff only', 'clone'};
            app.FlagMUforDropDown.ValueChangedFcn = createCallbackFcn(app, @FlagMUforDropDownValueChanged, true);
            app.FlagMUforDropDown.Placeholder = 'keep';
            app.FlagMUforDropDown.Value = 'keep';
            app.FlagMUforDropDownLabel = uilabel(app.Panel_3);
            app.FlagMUforDropDownLabel.Text = 'Flag MU for';
            app.PeeloffMUofcurrentgridSwitch = uiswitch(app.Panel_3, 'slider');
            app.PeeloffMUofcurrentgridSwitch.Items = {'all', 'specific:'};
            app.PeeloffMUofcurrentgridSwitch.Enable = 'off';
            app.PeeloffMUofcurrentgridSwitch.Value = 'specific:';
            app.seperatenumbersbycommaLabel = uilabel(app.Panel_3);
            app.seperatenumbersbycommaLabel.Enable = 'off';
            app.seperatenumbersbycommaLabel.Text = {'seperate numbers '; 'by comma'};
            app.seperatenumbersbycommaEditField = uieditfield(app.Panel_3, 'text');
            app.seperatenumbersbycommaEditField.Enable = 'off';
            app.PeeloffMUofcurrentgridSwitchLabel = uilabel(app.Panel_3);
            app.PeeloffMUofcurrentgridSwitchLabel.Enable = 'off';
            app.PeeloffMUofcurrentgridSwitchLabel.Text = 'Peeloff MU of current grid';
            app.PlotpeeloffsourcesButton = uibutton(app.Panel_3, 'push');
            app.PlotpeeloffsourcesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotpeeloffsourcesButtonPushed, true);
            app.PlotpeeloffsourcesButton.Enable = 'off';
            app.PlotpeeloffsourcesButton.Text = 'Plot peeloff sources';
            app.UsepeeledoffsignalCheckBox = uicheckbox(app.Panel_3);
            app.UsepeeledoffsignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsepeeledoffsignalCheckBoxValueChanged, true);
            app.UsepeeledoffsignalCheckBox.Text = 'Use peeled off signal';
            app.plotSTAButton = uibutton(app.Panel_3, 'push');
            app.plotSTAButton.ButtonPushedFcn = createCallbackFcn(app, @plotSTAButtonPushed, true);
            app.plotSTAButton.Text = 'plot STA';
            app.closefiguresButton = uibutton(app.Panel_3, 'push');
            app.closefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @closefiguresButtonPushed, true);
            app.closefiguresButton.Text = 'close figures';
            app.seperatenumbersbycommaEditField_2Label = uilabel(app.Panel_3);
            app.seperatenumbersbycommaEditField_2Label.Enable = 'off';
            app.seperatenumbersbycommaEditField_2Label.Text = {'seperate numbers '; 'by comma'};
            app.seperatenumbersbycommaEditField_2 = uieditfield(app.Panel_3, 'text');
            app.seperatenumbersbycommaEditField_2.Enable = 'off';
            app.IgnoreselectedwindowsduringfilterupdateLabel = uilabel(app.Panel_3);
            app.IgnoreselectedwindowsduringfilterupdateLabel.Text = 'Ignore windows during update';
            app.IgnorewindowsduringupdateSwitch = uiswitch(app.Panel_3, 'slider');
            app.IgnorewindowsduringupdateSwitch.Items = {'all', 'specific:'};
            app.IgnorewindowsduringupdateSwitch.Enable = 'off';
            app.IgnorewindowsduringupdateSwitch.Value = 'specific:';
            app.VisualizewindowDropDown = uidropdown(app.Panel_3);
            app.VisualizewindowDropDown.Items = {'none', 'all'};
            app.VisualizewindowDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VisualizewindowDropDownOpening, true);
            app.VisualizewindowDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizewindowDropDownValueChanged, true);
            app.VisualizewindowDropDown.Enable = 'off';
            app.VisualizewindowDropDown.Placeholder = 'none';
            app.VisualizewindowDropDown.Value = 'none';
            app.VisualizewindowDropDownLabel = uilabel(app.Panel_3);
            app.VisualizewindowDropDownLabel.Enable = 'off';
            app.VisualizewindowDropDownLabel.Text = 'Visualize window';
            app.AddwindowButton = uibutton(app.Panel_3, 'push');
            app.AddwindowButton.ButtonPushedFcn = createCallbackFcn(app, @AddwindowButtonPushed, true);
            app.AddwindowButton.Enable = 'off';
            app.AddwindowButton.Text = 'Add window';
            app.IgnorewindowsCheckBox = uicheckbox(app.Panel_3);
            app.IgnorewindowsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnorewindowsCheckBoxValueChanged, true);
            app.IgnorewindowsCheckBox.Text = 'Ignore windows';
            app.Panel_4 = uipanel(app.UIFigure);
            app.EditField_saving_2 = uieditfield(app.Panel_4, 'text');
            app.EditField_saving_2.Editable = 'off';
            app.EditField_saving_2.Value = 'File name';
            app.SelectfileButton_2 = uibutton(app.Panel_4, 'push');
            app.SelectfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButton_2Pushed, true);
            app.SelectfileButton_2.Text = 'Select file';
            app.ImportdataButton = uibutton(app.Panel_4, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.RemovealltheoutliersButton = uibutton(app.Panel_4, 'push');
            app.RemovealltheoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemovealltheoutliersButtonPushed, true);
            app.RemovealltheoutliersButton.Text = '1 - Remove all the outliers';
            app.UpdateallMUfiltersButton = uibutton(app.Panel_4, 'push');
            app.UpdateallMUfiltersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateallMUfiltersButtonPushed, true);
            app.UpdateallMUfiltersButton.Text = '2 - Update all MU filters';
            app.RemoveflaggedMUButton = uibutton(app.Panel_4, 'push');
            app.RemoveflaggedMUButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveflaggedMUButtonPushed, true);
            app.RemoveflaggedMUButton.Text = '3 - Remove flagged MU';
            app.PlotMUspiketrainsButton = uibutton(app.Panel_4, 'push');
            app.PlotMUspiketrainsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMUspiketrainsButtonPushed, true);
            app.PlotMUspiketrainsButton.Text = 'Plot MU spike trains';
            app.SaveButton = uibutton(app.Panel_4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BatchprocessingLabel = uilabel(app.Panel_4);
            app.BatchprocessingLabel.Text = 'Batch processing';
            app.VisualisationLabel = uilabel(app.Panel_4);
            app.VisualisationLabel.Text = 'Visualisation';
            app.SavetheeditionLabel = uilabel(app.Panel_4);
            app.SavetheeditionLabel.Text = 'Save the edition';
            app.PlotMUfiringratesButton = uibutton(app.Panel_4, 'push');
            app.PlotMUfiringratesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMUfiringratesButtonPushed, true);
            app.PlotMUfiringratesButton.Text = 'Plot MU firing rates';
            app.RemoveduplicateswithingridsButton = uibutton(app.Panel_4, 'push');
            app.RemoveduplicateswithingridsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveduplicateswithingridsButtonPushed, true);
            app.RemoveduplicateswithingridsButton.Text = '4 - Remove duplicates within grids';
            app.RemoveduplicatesbetweengridsButton = uibutton(app.Panel_4, 'push');
            app.RemoveduplicatesbetweengridsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveduplicatesbetweengridsButtonPushed, true);
            app.RemoveduplicatesbetweengridsButton.Text = '5 - Remove duplicates between grids';
            app.ReferenceDropDown_2Label = uilabel(app.Panel_4);
            app.ReferenceDropDown_2Label.Text = 'Reference';
            app.ReferenceDropDown_2 = uidropdown(app.Panel_4);
            app.ReferenceDropDown_2.Items = {'Target', 'Force', 'EMG amplitude', 'All'};
            app.ReferenceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ReferenceDropDown_2ValueChanged, true);
            app.ReferenceDropDown_2.Value = 'Target';
            app.CheckBox = uicheckbox(app.Panel_4);
            app.SILCheckBox = uicheckbox(app.Panel_4);
            app.SILCheckBox.ValueChangedFcn = createCallbackFcn(app, @SILCheckBoxValueChanged, true);
            app.SILCheckBox.Text = ' SIL';
            app.ClearflaggedcacheCheckBox = uicheckbox(app.Panel_4);
            app.ClearflaggedcacheCheckBox.Text = {'Clear '; 'flagged'; 'cache'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MUedit_peeloff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end